classdef plasticity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Button                        matlab.ui.control.Button
        EnterButton_4                 matlab.ui.control.Button
        CleanimageButton              matlab.ui.control.Button
        TensionButton                 matlab.ui.control.Button
        SelectROIDropDown             matlab.ui.control.DropDown
        SelectROIDropDownLabel        matlab.ui.control.Label
        YieldstrengthEditField        matlab.ui.control.NumericEditField
        YieldstrengthEditFieldLabel   matlab.ui.control.Label
        ExportstressstrainButton_2    matlab.ui.control.Button
        PoissonsratioEditFieldLabel   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ExportstressstrainButton      matlab.ui.control.Button
        InputparametersLabel          matlab.ui.control.Label
        BinaryconversionLabel         matlab.ui.control.Label
        InputimageLabel               matlab.ui.control.Label
        AreaEditField                 matlab.ui.control.NumericEditField
        AreaEditFieldLabel            matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        PoissonsratioEditField        matlab.ui.control.NumericEditField
        ImagebasedFEAoutputLabel      matlab.ui.control.Label
        AIBioMechpredictionLabel      matlab.ui.control.Label
        SelectoptionDropDown          matlab.ui.control.DropDown
        SelectoptionDropDownLabel     matlab.ui.control.Label
        NormalstressYButton_2         matlab.ui.control.Button
        NormalstressXButton_2         matlab.ui.control.Button
        ShearstressButton_2           matlab.ui.control.Button
        vonMisesstressButton_2        matlab.ui.control.Button
        YDisplacementButton_2         matlab.ui.control.Button
        XDisplacementButton_2         matlab.ui.control.Button
        StressstrainplotButton_2      matlab.ui.control.Button
        FEAtimesecEditField           matlab.ui.control.NumericEditField
        CompressionButton_2           matlab.ui.control.Button
        FEAtimesecEditFieldLabel      matlab.ui.control.Label
        PredictiontimesecEditField    matlab.ui.control.NumericEditField
        PredictiontimesecEditFieldLabel  matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        NormalstressYButton           matlab.ui.control.Button
        NormalstressXButton           matlab.ui.control.Button
        StressstrainplotButton        matlab.ui.control.Button
        SetunitDropDown               matlab.ui.control.DropDown
        SetunitDropDownLabel          matlab.ui.control.Label
        ShearstressButton             matlab.ui.control.Button
        vonMisesstressButton          matlab.ui.control.Button
        YDisplacementButton           matlab.ui.control.Button
        XDisplacementButton           matlab.ui.control.Button
        YoungsmodulusEditField        matlab.ui.control.NumericEditField
        YoungsmodulusEditFieldLabel   matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        YdisplacementEditField        matlab.ui.control.NumericEditField
        YdisplacementLabel            matlab.ui.control.Label
        CompressionButton             matlab.ui.control.Button
        CloseallButton                matlab.ui.control.Button
        MaterialpropertiesLabel       matlab.ui.control.Label
        EditField11                   matlab.ui.control.NumericEditField
        EnterButton                   matlab.ui.control.Button
        PhysicallengthEditField       matlab.ui.control.NumericEditField
        PhysicallengthEditFieldLabel  matlab.ui.control.Label
        PixelslengthEditField         matlab.ui.control.NumericEditField
        PixelslengthEditFieldLabel    matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        SetscaleButton                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        Menu_3                        matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
        ContextMenu4                  matlab.ui.container.ContextMenu
        Menu_4                        matlab.ui.container.Menu
        Menu2_4                       matlab.ui.container.Menu
        ContextMenu5                  matlab.ui.container.ContextMenu
        Menu_5                        matlab.ui.container.Menu
        Menu2_5                       matlab.ui.container.Menu
        ContextMenu6                  matlab.ui.container.ContextMenu
        Menu_6                        matlab.ui.container.Menu
        Menu2_6                       matlab.ui.container.Menu
        ContextMenu7                  matlab.ui.container.ContextMenu
        Menu_7                        matlab.ui.container.Menu
        Menu2_7                       matlab.ui.container.Menu
        ContextMenu8                  matlab.ui.container.ContextMenu
        Menu_8                        matlab.ui.container.Menu
        Menu2_8                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        img % Description
        scale % Description
        % Description
       
        dist
        % Description
        model1 % Description
        result1 % Description
        E % Description
        yieldStress % Description
        %Et % Description
        %fractureStressLimit % Description
        %fractureStrainPercent  % Description
        %fractureStress % Description
        %fractureStrain % Description
        %targetStrain % Description
        binaryImage % Description
         paddedImage % Description
        result2 % Description
        model2 % Description
         % Description
        %front_page % Description
        triangles % Description
        area % Description
        binaryimage % Description
        Et % Description
        binaryimage1 % Description
        AIBioMech % Description
        total_length % Description
        yield_disp_m % Description
        A % Description
        f % Description
        eraserSizeSlider % Description
        pixelCountText % Description
        eraserSizeText % Description
        erosionText % Description
        erosionSlider % Description
        dilationSlider % Description
        dilationText % Description
        Ep % Description
         % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statup)
             % Resize the UIFigure window to match your screen size
    screenSize = get(0, 'ScreenSize');  % [left bottom width height]
    
    % Position the UIFigure to fill the screen (excluding taskbar if needed)
    app.UIFigure.Position = screenSize;
    
    % Optional: center if you use smaller window
    % movegui(app.UIFigure, 'center');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            app.EditField.Value = '';
            app.PredictiontimesecEditField.Value=0;
            app.EditField_2.Value='';
           app.FEAtimesecEditField.Value=0;
           app.img = [];
           app.SelectROIDropDown.Value = 'Select options';
           app.result1 = [];
            app.result2 = [];
            [file, path]=uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
            end
            figure(app.UIFigure);
            app.img=imread(fullfile(path,file));
            app.img=imresize(app.img,[512 512]);
            imshow(app.img,'parent',app.UIAxes);
           % title(app.UIAxes,'Bio structure');

% Convert to grayscale if needed
if size(app.img,3) == 3
    imGray = im2gray(app.img);
else
    imGray = app.img;
end

% Check if image is already binary
if islogical(imGray) || all(ismember(imGray(:), [0 1]))
    app.binaryimage1 = imGray;    % Already binary, use as is
else
    app.binaryimage1 = imbinarize(imGray);  % Binarize
end
imshow(app.binaryimage1);
% Add black padding

        %app.binaryimage1=~(app.binaryimage1);
         %app.binaryimage1=~(app.binaryimage1)
        imshow(app.binaryimage1,'parent',app.UIAxes2);
        end

        % Button pushed function: SetscaleButton
        function SetscaleButtonPushed(app, event)
            if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            fig=figure;
             if isfield(app, 'fig') && isvalid(app.fig)
        % If the figure exists, close it
        close(app.fig);
             end
             scaleFactor = 1; % Adjust this according to your preference

% Scale the image
app.binaryimage1= imresize(app.binaryimage1, scaleFactor);
            imshow(app.binaryimage1);
            %title(app.UIAxes,'Original Image'); 
            %z=imfinfo(app.str)
            %Pixel_length=z.Width;
            %cross=Actual_length/Pixel_length;
            h=imdistline;
            wait(h)
            app.dist=h.getDistance()/scaleFactor;
             %dist=dist;
             app.PixelslengthEditField.Value=app.dist;
             delete(h);
             close(fig);
        end

        % Value changed function: PixelslengthEditField
        function PixelslengthEditFieldValueChanged(app, event)
            %value = app.PixelslengthEditField.Value;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            app.scale=app.PhysicallengthEditField.Value/app.PixelslengthEditField.Value;
            % imageHeightPixels = size(app.binaryImage,1)
            tt=app.SelectROIDropDown.Value;
% Process based on ROI selection
switch tt
    case 'White'
        % White region = 1
         numWhitePixels = sum(app.binaryimage1(:) == 1);

    case 'Black'
        % Black region = 0
       numWhitePixels = sum(app.binaryimage1(:) == 0);

    otherwise
         uialert(app.UIFigure, ...
        'Please select region of interest.', ...
        'Invalid ROI', ...
        'Icon','warning');
        return;
end


% Update UI
app.EditField11.Value = app.scale;

% Compute area
app.area = numWhitePixels*(app.scale*app.scale);

% Display area
app.AreaEditField.Value = app.area;

        end

        % Value changed function: PhysicallengthEditField
        function PhysicallengthEditFieldValueChanged(app, event)
           % value = app.PhysicallengthEditField.Value;
            
        end

        % Value changed function: EditField11
        function EditField11ValueChanged(app, event)
           % value = app.EditField11.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.AreaEditField.Value=0;
            app.EditField.Value = ''; 
            app.PixelslengthEditField.Value = 0;
            app.PhysicallengthEditField.Value = 0;
            app.EditField11.Value = 0;
            app.YdisplacementEditField.Value = 0;
            app.YoungsmodulusEditField.Value = 0;
%            app.ultimatestrengthEditField.Value = 0;
           % app.FracturestressEditField.Value = 0;
            %app.YeildingstrainEditField.Value = 0;
            %app.TangentmodulusEditField.Value= 0;
            app.PoissonsratioEditField.Value=0;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            app.EditField.Value = [];
            app.PredictiontimesecEditField.Value=0;
            app.YieldstrengthEditField.Value=0;
            app.EditField_2.Value='';
           app.FEAtimesecEditField.Value=0;
                for k = 1:14
    if isvalid(figure(k))  % Ensure figure exists
         close(k);            % Clear contents of figure k
    end
end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           % value = app.EditField.Value;
            
        end

        % Button pushed function: CompressionButton
        function CompressionButtonPushed2(app, event)
            close all
         if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
    
    % Simulate some processing
if app.YoungsmodulusEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Young''s Modulus value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end  


if app.YieldstrengthEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Yield strength value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end


nu = app.PoissonsratioEditField.Value;

% Validate input
if isnan(nu) || nu <= 0 || nu >= 0.5
    uialert(app.UIFigure, ...
        'Please enter a valid Poisson''s ratio between 0 and 0.5.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
end
 if app.YdisplacementEditField.Value == 0

% Validate input
    uialert(app.UIFigure, ...
        'Please enter a valid numeric value for Y displacement.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
 end
  d = uiprogressdlg(app.UIFigure, ...
    'Title','AI-BioMech Prediction', ...
    'Message','Processing...', ...
    'Indeterminate','on', ...
    'Cancelable','off');
  
            % Material properties
             tic;
app.E =  app.YoungsmodulusEditField.Value;           % Young's modulus (Pa)
%app.yieldStress =  app.YieldstrengthEditField.Value; % Yield stress (Pa)
%app.Et =app.TangentmodulusEditField.Value;        % Tangent modulus (plastic hardening)
%app.fractureStressLimit =  app.FracturestressEditField.Value;       
%app.fractureStrainPercent = app.YeildingstrainEditField.Value; 
% Fracture properties
%app.fractureStress = app.fractureStressLimit;     
%app.fractureStrain = app.fractureStrainPercent/100;  % convert to decimal
   % Fracture stress limit (Pa)
%app.targetStrain = app.fractureStrain;

img = app.paddedImage;
tt=app.SelectROIDropDown.Value;
% Process based on ROI selection
switch tt
    case 'White'
        % White region = 1
        app.binaryimage = (img == 1);

    case 'Black'
        % Black region = 0
        app.binaryimage = (img == 0);

    otherwise
        uialert(app.UIFigure, ...
        'Please select region of interest.', ...
        'Invalid ROI', ...
        'Icon','warning');
  
        return;
end

% Get row and column indices of selected region
[row, col] = find(app.binaryimage);

%% Step 3: Create a Grid of Points Inside the White Region
gridSpacing = 2; % Grid spacing (in pixels)
minX = min(col); maxX = max(col);
minY = min(row); maxY = max(row);
[xGrid, yGrid] = meshgrid(minX:gridSpacing:maxX, minY:gridSpacing:maxY);

xGrid = xGrid(:); yGrid = yGrid(:);

%% Step 4: Masking - Keep Only Points Inside the White Region
validIndices = app.binaryimage(sub2ind(size(app.binaryimage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices); validY = yGrid(validIndices);


%% Step 5: Delaunay Triangulation
app.triangles = delaunay(validX, validY);

%% Step 6: Remove Triangles That Cross Black Regions
validTriangles = [];
for i = 1:size(app.triangles, 1)
    triangleVertices = [validX(app.triangles(i, :)), validY(app.triangles(i, :))];
    if ~anyEdgeCrossesBlackRegion(triangleVertices, app.binaryimage)
        validTriangles = [validTriangles; app.triangles(i, :)];
    end
end

%% Step 7: Flip Y-coordinates to Match Cartesian System
validY = max(validY) - validY;

mesh.Vertices = [validX, max(validY) - validY];
%mesh.Vertices = [validX, max(validY) - validY] * 1e-3; % Convert pixels to meters (example)
mesh.Elements = validTriangles;

Vertices = mesh.Vertices;
elements = mesh.Elements;

nodes = Vertices';  % 2xN
elements = elements'; % 3xM

% Fix element orientation (counter-clockwise)
for i = 1:size(elements, 2)
    v1 = nodes(:, elements(1,i));
    v2 = nodes(:, elements(2,i));
    v3 = nodes(:, elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end
switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
       app.model1 = createpde('structural','static-planestress');

    otherwise
       app.model1 = createpde('structural','static-planestrain');  
end
% Create PDE model
%app.model1 = createpde('structural','static-planestrain');
geometryFromMesh(app.model1, nodes, elements);
%generateMesh(app.model1, 'Hmax', 0.5);
%generateMesh(app.model1, 'Hmax', 0.1);
% Define material properties
P= app.PoissonsratioEditField.Value;
structuralProperties(app.model1, 'YoungsModulus', app.E, 'PoissonsRatio', P);

% Find top and bottom edges for BC (nearestEdge function or manual)
vertices = app.model1.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));

topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];

topEdgeID = nearestEdge(app.model1.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model1.Geometry, bottomEdgePoint);

% Apply displacement BC at top (yield strain displacement)
app.yield_disp_m =  -(app.YdisplacementEditField.Value/100) * (maxY - minY); % approximate displacement at yield in meters
structuralBC(app.model1, 'Edge', topEdgeID, 'Displacement', [0, app.yield_disp_m]); % downward displacement
%structuralBC(app.model1, 'Edge', bottomEdgeID, 'YDisplacement', 0);
structuralBC(app.model1, 'Edge', bottomEdgeID, 'Displacement', [0 0]);
% Solve model
try
    app.result1 = solve(app.model1);   % run solver

catch
    uialert(app.UIFigure, ...
        'Please provide a high-resolution image\n.The selected region is invalid or contains disconnected patches', ...
        'Prediction Failed', ...
        'Icon','error');

    if exist('h1','var') && isvalid(h1)
        pause(2);     % show for 2 seconds
close(d);
    end

    return;   % stop execution
end
pause(2);     % show for 2 seconds
close(d);
if app.PixelslengthEditField.Value == 0 && app.PhysicallengthEditField.Value == 0
    uialert(app.UIFigure, ...
        ['Scale has not been set.' ...
         'All results are calculated using the default pixel scale.'], ...
        'Default Scale', ...
        'Icon','info');
end
toc;
 

%% ================== EXTRACT DATA ==================
% Get von Mises stress data
%% ================== EXTRACT MAXIMUM VALUES ==================
% Get maximum von Mises stress from results
%% ================== STRAIN DATA ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Average equivalent strain (global)
avgStrain = mean(eqStrain, 'all');
% Maximum equivalent strain
maxStrainValue = max(eqStrain(:));

%% ================== STRESS DATA ==================
vmStress = app.result1.VonMisesStress;   % Pa

% Average equivalent stress
avgStress = mean(vmStress, 'all');
% Maximum von Mises stress
maxStressValue = max(vmStress(:));

%% ================== GEOMETRY-BASED ELASTIC MODULUS ==================
% Use AVERAGE values to calculate effective modulus
E_eff = avgStress / avgStrain;           % Pa

%% ================== BUILD ELASTIC CURVE ==================
nPts = 300;
% Use MAX strain for plotting to show full range
strainLine = linspace(0, maxStrainValue, nPts);   % dimensionless
stressLine = E_eff * strainLine;             % Pa

%% ================== UNIT CONVERSION ==================
strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== TIMING ==================
elapsedTime = toc;
app.PredictiontimesecEditField.Value = elapsedTime;

%% ================== MAX VALUES ==================
% Use ACTUAL maximum values from simulation
% NOT the endpoint of the calculated curve
maxStrainPercent = maxStrainValue * 100;  % Actual max from simulation
maxStressMPa = maxStressValue / 1e6;      % Actual max from simulation
maxStrainValue = strainLine(end);  % Use end of strainLine, not max from simulation
maxStressValue = stressLine(end);
app.EditField_2.Value = sprintf( ...
    'Max strain = %.3f %%\nMax stress = %.2f MPa', ...
    maxStrainValue*100, maxStressValue/1e6 );
        end

        % Value changed function: YdisplacementEditField
        function YdisplacementEditFieldValueChanged(app, event)
           % value = app.YdisplacementEditField.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: YoungsmodulusEditField
        function YoungsmodulusEditFieldValueChanged(app, event)
            value = app.YoungsmodulusEditField.Value;
            
        end

        % Callback function
        function ultimatestrengthEditFieldValueChanged(app, event)
            %value = app.ultimatestrengthEditField.Value;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: vonMisesstressButton
        function vonMisesstressButtonPushed(app, event)
            if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end% Get actual min and max von Mises stress from data (for color axis)


 % Get actual min and max von Mises stress from data (for color axis)
% Check if EditField_2 is emptyvalStr = strtrim(app.EditField_2.Value);
valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end

vonmisesstress=app.result1.VonMisesStress;
vonmises_MPa = vonmisesstress/1e6;  % Convert to MPa

%percentileStress = prctile(vonmises_MPa(:), 99);
% Maximum and minimum
maxStress = max(vonmises_MPa(:));
minStress = min(vonmises_MPa(:));

% Display in command window
fprintf('Maximum Von Mises Stress: %.2f MPa\n', maxStress);
fprintf('Minimum Von Mises Stress: %.2f MPa\n', minStress);
figure (2);
clf;
pdeplot(app.model1, 'XYData', vonmises_MPa, 'Colormap', 'jet');
set(gca, 'YDir', 'reverse');
title('AI-BioMech predicted Von Mises stress (MPa)')
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')

% Tight color limits
%clim([0, percentileStress]);
colormap(jet)  
axis off;

        end

        % Button pushed function: StressstrainplotButton
        function StressstrainplotButtonPushed(app, event)
          if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
%% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================
          %% ================== INPUTS ==================
%% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================

        
            %% ================== INPUTS ==================
E_mat   = app.E;                                  
Ep      = 0.01 * E_mat;
%Ep=1;% post-yield tangent
sigma_y = app.YieldstrengthEditField.Value;       % material yield stress

% Theoretical yield strain
yieldStrain_theoretical = sigma_y / E_mat;

%% ================== FEA DATA ==================
exx = app.result1.Strain.exx(:);
eyy = app.result1.Strain.eyy(:);
exy = app.result1.Strain.exy(:);

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Von Mises stress
vmStress = app.result1.VonMisesStress(:);

% Remove NaNs/Infs
validIdx = ~isnan(eqStrain) & ~isnan(vmStress) & ...
           isfinite(eqStrain) & isfinite(vmStress);

eqStrain = eqStrain(validIdx);
vmStress = vmStress(validIdx);

% Sort for smooth plotting
[eqStrain, sortIdx] = sort(eqStrain);
vmStress = vmStress(sortIdx);

maxStrain = max(eqStrain);
maxStress = max(vmStress);

%% ================== CHECK IF YIELD OCCURS ==================
hasYielded = any(eqStrain >= yieldStrain_theoretical);

if hasYielded
    yieldIdx = find(eqStrain >= yieldStrain_theoretical, 1, 'first');
    
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
    % Clamp stress if exceeding material yield
    if stress_at_yield > sigma_y
        stress_at_yield = sigma_y;
    end
else
    strain_at_yield = maxStrain;
    stress_at_yield = maxStress;
end

%% ================== BUILD CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));

if hasYielded
    % Elastic slope up to yield
    E_effective = stress_at_yield / strain_at_yield;
    
    for i = 1:nPts
        if strainLine(i) <= strain_at_yield
            % Elastic region
            stressLine(i) = E_effective * strainLine(i);
        else
            % Plastic region
            stressLine(i) = stress_at_yield + ...
                            Ep * (strainLine(i) - strain_at_yield);
        end
    end
else
    % Pure linear elastic
    E_linear = maxStress / maxStrain;
    stressLine = E_linear * strainLine;
end

%% ================== PLOT ==================
figure('Name','AI_BioMech predicted stress-strain curve','NumberTitle','off');
hold on; grid on; box on;

plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);

if hasYielded
    plot(strain_at_yield*100, stress_at_yield/1e6, ...
        'ro','MarkerFaceColor','r','MarkerSize',8);
    legend('Bilinear Curve','Yield Point','Location','best');
else
    legend('Linear Elastic Curve','Location','best');
end

xlabel('Strain (%)');
ylabel('Stress (MPa)');
title('AI-BioMech predicted stress-strain curve');

%% ================== DISPLAY VALUES ==================
if hasYielded
    app.EditField_2.Value = sprintf([ ...
        'Yield stress = %.2f MPa \n', ...
        'Yield strain = %.2f %% \n\n'], ...
       stress_at_yield/1e6, strain_at_yield*100);
else
    app.EditField_2.Value = sprintf([ ...
        'Max stress = %.2f MPa\n', ...
        'Max strain = %.2f %%'], ...
         maxStress/1e6, maxStrain*100);
end

%% ================== DATACURSOR ==================
fig = gcf;
dcm = datacursormode(fig);
set(dcm,'Enable','on');
dcm.UpdateFcn = @(~,event) myCursor1(event);
        end

        % Button pushed function: XDisplacementButton
        function XDisplacementButtonPushed(app, event)
 if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField_2.Value);

% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            ux = app.result1.Displacement.ux;

% Check if app.scale exists and is nonzero
if isprop(app, 'scale') && ~isempty(app.scale) && app.scale ~= 0
    scaledDisplacement = app.scale * ux;

else
    scaledDisplacement = ux;
   
end

% Plot
figure(4);
pdeplot(app.model1, 'XYData', scaledDisplacement, 'Colormap', 'jet');
title('AI-BioMech predicted X-displacement magnitude');
axis equal;
axis tight;
axis xy;
% Remove axes
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
%cb = colorbar;
%cb.Label.String = 'XDisplacement';  %
        end

        % Button pushed function: YDisplacementButton
        function YDisplacementButtonPushed(app, event)
 if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            uy = app.result1.Displacement.uy;

% Check if app.scale exists and is nonzero
if isprop(app, 'scale') && ~isempty(app.scale) && app.scale ~= 0
    scaledDisplacement = app.scale * uy;
else
    scaledDisplacement = uy;
end

% Plot
figure(5);
pdeplot(app.model1, 'XYData', scaledDisplacement, 'Colormap', 'jet');
title('AI-BioMech predicted Y-displacement magnitude');
axis equal;

xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
axis off;
        end

        % Button pushed function: ShearstressButton
        function ShearstressButtonPushed(app, event)
 if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            stress = app.result1.Stress;
shearStress = stress.sxy;             % Pa
shearStress_MPa = shearStress / 1e6;  % Convert to MPa

figure(3); 
pdeplot(app.model1, 'XYData', shearStress_MPa, 'ColorMap', 'jet');

% Axis properties
axis equal;
set(gca, 'YDir', 'reverse');
xlabel(''); ylabel('');
set(gca, 'XTick', [], 'YTick', []);
axis off;

% Add colorbar
cb = colorbar;


% ✅ Set color limits (clim) manually
clim([min(shearStress_MPa(:)), max(shearStress_MPa(:))]);  % tight to data

title('AI-BioMech Predicted Shear Stress (\tau_{xy})');

% Add colorbar with units
%cb = colorbar;
%cb.Label.String = 'Shear stress (MPa)';  % show units

        end

        % Button pushed function: NormalstressYButton
        function NormalstressYButtonPushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
          valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
          stress = app.result1.Stress;

% Extract normal stress
normalStress = stress.syy;  % Change to syy or szz as needed

% Compute normal yield and fracture limits
%normalYield = app.yieldStress;
%normalFracture = app.fractureStress;

% Create mask for different regions
%elasticRegion = abs(normalStress) <= normalYield;
%plasticRegion = abs(normalStress) > normalYield & abs(normalStress) <= normalFracture;
%fractureRegion = abs(normalStress) > normalFracture;

% Saturate stress for visualization
%normalStressCapped = min(normalStress, normalFracture);  % Clip upper bound

% Start figure
figure(7); 
pdeplot(app.model1, 'XYData',normalStress/1e6, 'ColorMap', 'jet');
set(gca, 'YDir', 'reverse');
title('AI-BioMech predicted normal stress(\sigma_{yy})(MPa)');  % Update the title
colorbar;
%clim([min(normalStress(:)), normalFracture]);  % Fix color scale
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
% Update colorbar label
%cb = colorbar;
%cb.Label.String = 'Normal Stress (\sigma_{yy})';  


        end

        % Button pushed function: NormalstressXButton
        function NormalstressXButtonPushed(app, event)
          if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
           valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            % Visualize shear stress (\tau_{xy})
  stress = app.result1.Stress;

% Extract normal stress
normalStress = stress.sxx;  % Change to syy or szz as needed

% Compute normal yield and fracture limits
%normalYield = app.yieldStress;
%normalFracture = app.fractureStress;

% Create mask for different regions
%elasticRegion = abs(normalStress) <= normalYield;
%plasticRegion = abs(normalStress) > normalYield & abs(normalStress) <= normalFracture;
%fractureRegion = abs(normalStress) > normalFracture;

% Saturate stress for visualization
%normalStressCapped = min(normalStress, normalFracture);  % Clip upper bound

% Start figure
figure(6); 
pdeplot(app.model1, 'XYData', normalStress/1e6, 'ColorMap', 'jet');
set(gca, 'YDir', 'reverse');
title('AI-BioMech predicted normal stress(\sigma_{xx}) (MPa)');  % Update the title
colorbar;
%clim([min(normalStress(:)), normalFracture]);  % Fix color scale
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
% Update colorbar label
%cb = colorbar;
%cb.Label.String = 'Normal Stress (\sigma_{xx})';


        end

        % Button pushed function: CompressionButton_2
        function CompressionButton_2Pushed(app, event)
       if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
        app.result2=[];
        app.model2=[];
        close all
        app.EditField.Value='';


    % Simulate some processing
if app.YoungsmodulusEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Young''s Modulus value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end  


if app.YieldstrengthEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Yield strength value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end


nu = app.PoissonsratioEditField.Value;

% Validate input
if isnan(nu) || nu <= 0 || nu >= 0.5
    uialert(app.UIFigure, ...
        'Please enter a valid Poisson''s ratio between 0 and 0.5.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
end
 if app.YdisplacementEditField.Value == 0

% Validate input
    uialert(app.UIFigure, ...
        'Please enter a valid numeric value for Y displacement.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
 end
  d = uiprogressdlg(app.UIFigure, ...
    'Title','Image-based FEA processing', ...
    'Message','Processing...', ...
    'Indeterminate','on', ...
    'Cancelable','off');
  
            % Material properties
            
             tic;
    
    % Simulate some processing
   
app.E =  app.YoungsmodulusEditField.Value
fprintf('Value of app.E: %f\n', app.E)
E_mat=app.E;
Ep = 0.01 * E_mat

%Ep=2.1;
% tangent modulus (Pa)
%app.yieldStress =  app.YieldstrengthEditField.Value; % Yield stress (Pa)
%app.Et =app.TangentmodulusEditField.Value;        % Tangent modulus (plastic hardening)
%app.fractureStressLimit =  app.FracturestressEditField.Value;       
%app.fractureStrainPercent = app.YeildingstrainEditField.Value; 
% Fracture properties
%app.fractureStress = app.fractureStressLimit;     
%app.fractureStrain = app.fractureStrainPercent/100;  % convert to decimal
   % Fracture stress limit (Pa)
%app.targetStrain = app.fractureStrain;
 %app.binaryimage=imresize(app.binaryimage,[1024 1024]);

 img = app.paddedImage;

% Process based on ROI selection
switch app.SelectROIDropDown.Value
    case 'White'
        % White region = 1
        app.binaryimage = (img == 1);

    case 'Black'
        % Black region = 0
        app.binaryimage = (img == 0);

    otherwise
         uialert(app.UIFigure, ...
        'Please select region of interest.', ...
        'Invalid ROI', ...
        'Icon','warning');
        return;
end

% Get row and column indices of selected region
[row, col] = find(app.binaryimage);

%% Step 3: Create a Grid of Points Inside the White Region
gridSpacing = 1.9; % Grid spacing (in pixels)
minX = min(col); maxX = max(col);
minY = min(row); maxY = max(row);
[xGrid, yGrid] = meshgrid(minX:gridSpacing:maxX, minY:gridSpacing:maxY);

xGrid = xGrid(:); yGrid = yGrid(:);

%% Step 4: Masking - Keep Only Points Inside the White Region
validIndices = app.binaryimage(sub2ind(size(app.binaryimage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices); validY = yGrid(validIndices);


%% Step 5: Delaunay Triangulation
app.triangles = delaunay(validX, validY);

%% Step 6: Remove Triangles That Cross Black Regions
validTriangles = [];
for i = 1:size(app.triangles, 1)
    triangleVertices = [validX(app.triangles(i, :)), validY(app.triangles(i, :))];
    if ~anyEdgeCrossesBlackRegion(triangleVertices, app.binaryimage)
        validTriangles = [validTriangles; app.triangles(i, :)];
    end
end

%% Step 7: Flip Y-coordinates to Match Cartesian System
validY = max(validY) - validY;

mesh.Vertices = [validX, max(validY) - validY];
%mesh.Vertices = [validX, max(validY) - validY] * 1e-3; % Convert pixels to meters (example)
mesh.Elements = validTriangles;

Vertices = mesh.Vertices;
elements = mesh.Elements;

nodes = Vertices';  % 2xN
elements = elements'; % 3xM

% Fix element orientation (counter-clockwise)
for i = 1:size(elements, 2)
    v1 = nodes(:, elements(1,i));
    v2 = nodes(:, elements(2,i));
    v3 = nodes(:, elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end

switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
       app.model2 = createpde('structural','static-planestress');

    otherwise
       app.model2 = createpde('structural','static-planestrain');  
end


% Create PDE model
%app.model1 = createpde('structural','static-planestrain');
geometryFromMesh(app.model2, nodes, elements);
%generateMesh(app.model1, 'Hmax', 0.5);
%generateMesh(app.model1, 'Hmax', 0.1);
% Define material properties
P= app.PoissonsratioEditField.Value;
structuralProperties(app.model2, 'YoungsModulus', app.E, 'PoissonsRatio', P);
%structuralProperties(app.model2, 'YoungsModulus', app.E, 'PoissonsRatio', P, 'TangentModulus',1);
% Find top and bottom edges for BC (nearestEdge function or manual)
vertices = app.model2.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));

topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];

topEdgeID = nearestEdge(app.model2.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model2.Geometry, bottomEdgePoint);
%disp=app.YdisplacementEditField.Value;

% Apply displacement BC at top (yield strain displacement)
yield_disp_m =  -(app.YdisplacementEditField.Value/100) * (maxY - minY);% approximate displacement at yield in meters
structuralBC(app.model2, 'Edge', topEdgeID, 'Displacement', [0, yield_disp_m]); % downward displacement
structuralBC(app.model2, 'Edge', bottomEdgeID, 'YDisplacement', 0);

% Solve model
app.result2 = solve(app.model2);

pause(60);
toc
 
%% ================== EXTRACT DATA ==================
% Get von Mises stress data
%% ================== EXTRACT MAXIMUM VALUES ==================
exx = app.result2.Strain.exx;
eyy = app.result2.Strain.eyy;
exy = app.result2.Strain.exy;

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Average equivalent strain (global)
avgStrain = mean(eqStrain, 'all');
% Maximum equivalent strain
maxStrainValue = max(eqStrain(:));

%% ================== STRESS DATA ==================
vmStress = app.result2.VonMisesStress;   % Pa

% Average equivalent stress
avgStress = mean(vmStress, 'all');
% Maximum von Mises stress
maxStressValue = max(vmStress(:));

%% ================== GEOMETRY-BASED ELASTIC MODULUS ==================
% Use AVERAGE values to calculate effective modulus
E_eff = avgStress / avgStrain;           % Pa

%% ================== BUILD ELASTIC CURVE ==================
nPts = 300;
% Use MAX strain for plotting to show full range
strainLine = linspace(0, maxStrainValue, nPts);   % dimensionless
stressLine = E_eff * strainLine;             % Pa

%% ================== UNIT CONVERSION ==================
strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== TIMING ==================
elapsedTime = toc;
app.FEAtimesecEditField.Value= elapsedTime;

%% ================== MAX VALUES ==================
% Use ACTUAL maximum values from simulation
% NOT the endpoint of the calculated curve
maxStrainPercent = maxStrainValue * 100;  % Actual max from simulation
maxStressMPa = maxStressValue / 1e6;      % Actual max from simulation

%% ================== MAX VALUES ==================
maxStrainValue = strainLine(end);  % Use end of strainLine, not max from simulation
maxStressValue = stressLine(end);
app.EditField.Value = sprintf( ...
    'Max strain = %.3f %%\nMax stress = %.2f MPa', ...
    maxStrainValue*100, maxStressValue/1e6 );

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            %value = app.EditField_2.Value;
            
        end

        % Value changed function: PredictiontimesecEditField
        function PredictiontimesecEditFieldValueChanged(app, event)
            %value = app.PredictiontimesecEditField.Value;
            
        end

        % Value changed function: FEAtimesecEditField
        function FEAtimesecEditFieldValueChanged(app, event)
            value = app.FEAtimesecEditField.Value;
            
        end

        % Button pushed function: StressstrainplotButton_2
        function StressstrainplotButton_2Pushed(app, event)
     if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
E_mat   = app.E;
app.Ep      = (0.2 * app.E);   % tangent modulus (Ep)
sigma_y = app.YieldstrengthEditField.Value;  % material yield stress

%% ================== FEA DATA ==================
exx = app.result2.Strain.exx(:);
eyy = app.result2.Strain.eyy(:);
exy = app.result2.Strain.exy(:);

% Equivalent strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Von Mises stress
vmStress = app.result2.VonMisesStress(:);

%% Remove NaNs/Infs
validIdx = isfinite(eqStrain) & isfinite(vmStress);

eqStrain = eqStrain(validIdx);
vmStress = vmStress(validIdx);

% Sort for smooth curve
[eqStrain, sortIdx] = sort(eqStrain);
vmStress = vmStress(sortIdx);

maxStrain = max(eqStrain);

%% ================== YIELD DETECTION (FROM YOUR FEA ONLY) ==================
yieldStrain_theoretical = sigma_y / E_mat;

hasYielded = any(eqStrain >= yieldStrain_theoretical);

if hasYielded
    
    yieldIdx = find(eqStrain >= yieldStrain_theoretical, 1, 'first');
    
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
else
    
    strain_at_yield = maxStrain;
    stress_at_yield = max(vmStress);
end

%% ================== BUILD CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));
app.Et=app.Ep/10;
% Effective elastic slope from FEM
E_effective = stress_at_yield / strain_at_yield;

for i = 1:nPts
    
    if strainLine(i) <= strain_at_yield
        
        % ================== ELASTIC REGION (FROM FEA) ==================
        stressLine(i) = E_effective * strainLine(i);
        
    else
        
        % ================== PLASTIC REGION (FAKE EXTENSION USING Et) ==================
        stressLine(i) = stress_at_yield + app.Et * (strainLine(i) - strain_at_yield);
        
    end
end

%% ================== PLOT ==================
figure('Name','AI-BioMech Stress-Strain Curve','NumberTitle','off');
hold on; grid on; box on;

plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);

if hasYielded
    plot(strain_at_yield*100, stress_at_yield/1e6, ...
        'ro','MarkerFaceColor','r','MarkerSize',8);
    legend('Bilinear Curve','Yield Point','Location','best');
else
    legend('Linear Elastic Curve','Location','best');
end

xlabel('Strain (%)');
ylabel('Stress (MPa)');
title('AI-BioMech Predicted Stress–Strain Curve');

%% ================== DISPLAY ==================
if hasYielded
    app.EditField_2.Value = sprintf([ ...
        'Yield stress = %.2f MPa\n' ...
        'Yield strain = %.5f %%\n'], ...
        stress_at_yield/1e6, strain_at_yield*100);
else
    app.EditField_2.Value = sprintf([ ...
        'Max stress = %.2f MPa\n' ...
        'Max strain = %.2f %%'], ...
        max(vmStress)/1e6, maxStrain*100);
end

%% ================== DATACURSOR ==================
fig = gcf;
dcm = datacursormode(fig);
set(dcm,'Enable','on');
dcm.UpdateFcn = @(~,event) myCursor1(event);
        end

        % Button pushed function: XDisplacementButton_2
        function XDisplacementButton_2Pushed(app, event)
 if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
 ux = app.result2.Displacement.ux;

% Check if app.scale exists and is nonzero
if isprop(app, 'scale') && ~isempty(app.scale) && app.scale ~= 0
    scaledDisplacement = app.scale * ux;

else
    scaledDisplacement = ux;
   
end

% Plot
figure(10)
clf;
pdeplot(app.model2, 'XYData', scaledDisplacement, 'Colormap', 'jet');
title('Image based FEA X-displacement magnitude');
axis equal;
axis tight;
axis xy;
% Remove axes
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
%cb = colorbar;
%cb.Label.String = 'XDisplacement';  %

        end

        % Button pushed function: vonMisesstressButton_2
        function vonMisesstressButton_2Pushed(app, event)
  if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            % Get actual min and max von Mises stress from data (for color axis)
% Check if EditField_2 is emptyvalStr = strtrim(app.EditField_2.Value);
valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end

vonmisesstress = app.result2.VonMisesStress;
vonmises_MPa = vonmisesstress/1e6; % Convert to MPa

% Display statistics
maxStress = max(vonmises_MPa(:));
minStress = min(vonmises_MPa(:));
fprintf('Maximum Von Mises Stress: %.2f MPa\n', maxStress);
fprintf('Minimum Von Mises Stress: %.2f MPa\n', minStress);

% Apply power law transformation (gamma correction)
gamma = 0.6;  % Adjust for more/less contrast (0.3-0.7)
powerStress = (vonmises_MPa - minStress) ./ (maxStress - minStress);
powerStress = powerStress .^ gamma;
figure(7)
clf;
% Create the plot
pdeplot(app.model2, 'XYData', powerStress, 'Colormap', 'jet');
title('Image based FEA von Mises stress (MPa)');
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')

% Add colorbar with actual stress values
cbar = colorbar;

% Define tick positions in normalized space (0 to 1)
numTicks = 5; % Number of ticks you want on the colorbar
tickPositions = linspace(0, 1, numTicks);

% Calculate corresponding stress values for these positions
% Reverse the power law transformation for tick labels
tickValues = minStress + (maxStress - minStress) * (tickPositions .^ (1/gamma));

% Format tick labels with appropriate precision
tickLabels = arrayfun(@(x) sprintf('%.1f', x), tickValues, 'UniformOutput', false);

% Set custom ticks and labels
cbar.Ticks = tickPositions;
cbar.TickLabels = tickLabels;

% Label the colorbar
cbar.Label.String = ('von Mises stress (MPa)');
        end

        % Button pushed function: YDisplacementButton_2
        function YDisplacementButton_2Pushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
           uy = app.result2.Displacement.uy;

% Check if app.scale exists and is nonzero
if isprop(app, 'scale') && ~isempty(app.scale) && app.scale ~= 0
    scaledDisplacement = app.scale * uy;
else
    scaledDisplacement = uy;
end

% Plot
figure(11)
clf;
pdeplot(app.model2, 'XYData', scaledDisplacement, 'Colormap', 'jet');
title('Image based FEA Y-displacement magnitude');
axis equal;

xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')
axis off;
        end

        % Button pushed function: ShearstressButton_2
        function ShearstressButton_2Pushed(app, event)
          if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
           stress = app.result2.Stress;
shearStress = stress.sxy;             % Pa
shearStress_MPa = shearStress / 1e6;  % Convert to MPa

figure(8); 
pdeplot(app.model2, 'XYData', shearStress_MPa, 'ColorMap', 'jet');

% Axis properties
axis equal;
set(gca, 'YDir', 'reverse');
xlabel(''); ylabel('');
set(gca, 'XTick', [], 'YTick', []);
axis off;

% Add colorbar
cb = colorbar;


% ✅ Set color limits (clim) manually
clim([min(shearStress_MPa(:)), max(shearStress_MPa(:))]);  % tight to data

title('Image based shear stress (\tau_{xy})');

% Add colorbar with units
%cb = colorbar;
%cb.Label.String = 'Shear stress (MPa)';  % show units
        end

        % Button pushed function: NormalstressXButton_2
        function NormalstressXButton_2Pushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
            % Visualize shear stress (\tau_{xy})
  stress = app.result2.Stress;

% Extract normal stress
normalStress = stress.sxx;  % Change to syy or szz as needed


% Start figure
figure(12)
clf;
pdeplot(app.model2, 'XYData', normalStress/1e6, 'ColorMap', 'jet');
set(gca, 'YDir', 'reverse');
title('Image-based FEA normal stress(\sigma_{xx})(MPa)');  % Update the title
colorbar;
%clim([min(normalStress(:)), normalFracture]);  % Fix color scale
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')

% Tight color limits
%clim([0, percentileStress]);
colormap(jet)  
axis off;

% Update colorbar label
%cb = colorbar;
%cb.Label.String = 'Normal Stress (\sigma_{xx})';
        end

        % Button pushed function: NormalstressYButton_2
        function NormalstressYButton_2Pushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end 
            
            stress = app.result2.Stress;

% Extract normal stress
normalStress = stress.syy;  % Change to syy or szz as needed
figure(13)
clf; 
pdeplot(app.model2, 'XYData',normalStress/1e6, 'ColorMap', 'jet');
set(gca, 'YDir', 'reverse');
title('Image-based normal stress(\sigma_{yy})(MPa)');  % Update the title
colorbar;
%clim([min(normalStress(:)), normalFracture]);  % Fix color scale
axis equal;
xlabel(''); ylabel('');
set(gca, 'XTick', []); set(gca, 'YTick', []);
axis off;
set(gca,'YDir','reverse')

% Tight color limits
%clim([0, percentileStress]);
colormap(jet)  
axis off;


        end

        % Value changed function: SetunitDropDown
        function SetunitDropDownValueChanged(app, event)
            %value = app.SetunitDropDown.Value;
            
        end

        % Value changed function: SelectoptionDropDown
        function SelectoptionDropDownValueChanged(app, event)
            %value = app.SelectoptionDropDown.Value;
            
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            for k = 1:14
               if isvalid(figure(k))  % Ensure figure exists
               close(k);            % Clear contents of figure k
               end
             end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
     newApp = AIBioMech();  % open front page
    drawnow;
      pause(2);% make sure it renders
    delete(app); 
        end

        % Value changed function: PoissonsratioEditField
        function PoissonsratioEditFieldValueChanged(app, event)
            %value = app.PoissonsratioEditField.Value;
            
        end

        % Callback function
        function ButtonPushed(app, event)
%% --- Step 0: Prepare binary image ---
app.binaryImage = ~(app.paddedImage);  % Invert so white = region of interest

%% --- Step 1: Find white pixels ---
[row, col] = find(app.binaryImage == 1);

%% --- Step 2: Create grid of points inside white region ---
gridSpacing = 2;  % Adjust as needed
minX = min(col); maxX = max(col);
minY = min(row); maxY = max(row);

[xGrid, yGrid] = meshgrid(minX:gridSpacing:maxX, minY:gridSpacing:maxY);
xGrid = xGrid(:);
yGrid = yGrid(:);

%% --- Step 3: Keep only points inside white region ---
validIndices = app.binaryImage(sub2ind(size(app.binaryImage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices);
validY = yGrid(validIndices);

%% --- Step 4: Delaunay triangulation ---
tri = delaunay(validX, validY);

%% --- Step 5: Remove triangles crossing black regions ---
mask = false(size(tri,1),1);
for i = 1:size(tri,1)
    verts = [validX(tri(i,:)), validY(tri(i,:))];
    if ~anyEdgeCrossesBlackRegion(verts, app.binaryImage)
        mask(i) = true;
    end
end
validTriangles = tri(mask,:);

%% --- Step 6: Flip Y to Cartesian coordinates ---
validY = max(validY) - validY;

%% --- Step 7: Prepare nodes and elements for PDE ---
nodes = [validX'; validY'];       % 2 x N
elements = validTriangles';       % 3 x M

% Ensure elements have counter-clockwise orientation
for i = 1:size(elements,2)
    v1 = nodes(:,elements(1,i));
    v2 = nodes(:,elements(2,i));
    v3 = nodes(:,elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end

%% --- Step 8: Optional scaling to meters ---
nodes = nodes * app.scale;

%% --- Step 9: Validate mesh ---
if isempty(elements)
    error('No valid triangles found. Try reducing gridSpacing or check the binary image.');
end

%% --- Step 10: Create PDE model ---
switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
        app.model1 = createpde('structural','static-planestress');
    otherwise
        app.model1 = createpde('structural','static-planestrain');
end

%% --- Step 11: Import mesh into PDE model ---
geometryFromMesh(app.model1, nodes, elements);

%% --- Step 8b: Scale nodes to meters ---
nodes = nodes * app.scale; 

%% Step 6: Create PDE structural model
switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
       app.model1 = createpde('structural','static-planestress');

    otherwise
       app.model1 = createpde('structural','static-planestrain');  
end
geometryFromMesh(app.model1, nodes', elements);

%% Step 7: Define material properties
p= app.PoissonsratioEditField.Value;
structuralProperties(app.model1, 'YoungsModulus', app.E, 'PoissonsRatio', p);


%% Step 8: Identify top and bottom edges


%% Step 9: Apply displacement BCs in real-world units
% Find top and bottom edges for BC (nearestEdge function or manual)
vertices = app.model1.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));

topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];

topEdgeID = nearestEdge(app.model1.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model1.Geometry, bottomEdgePoint);

% Apply displacement BC at top (yield strain displacement)
yield_disp_m =  [app.YdisplacementEditField.Value]*10e-6; % approximate displacement at yield in meters
structuralBC(app.model1, 'Edge', topEdgeID, 'Displacement', [0, yield_disp_m]); % downward displacement
structuralBC(app.model1, 'Edge', bottomEdgeID, 'YDisplacement', 0);

% Solve model
app.result1 = solve(app.model1);
F = evaluateReaction(app.result1,'Edge',topEdgeID);
Fx_total = sum(F.Fx); % N
Fy_total = sum(F.Fy); % N
Fmag = sqrt(Fx_total^2 + Fy_total^2)*10e-6;
app.EditField_3.Value=Fmag;
        end

        % Value changed function: AreaEditField
        function AreaEditFieldValueChanged(app, event)
            %value = app.AreaEditField.Value;
            
        end

        % Callback function
        function stressPAEditFieldValueChanged(app, event)
           % value = app.stressPAEditField.Value;
            
        end

        % Callback function
        function AreamEditFieldValueChanged(app, event)
            %value = app.AreamEditField.Value;
            
        end

        % Callback function
        function ButtonPushed2(app, event)
          
%vertices = app.model1.Geometry.Vertices;
%maxY = max(vertices(:,2));
%topEdgeNodes = find(abs(app.model1.Mesh.Nodes(2,:) - maxY) < 1e-12); 
stress = app.result1.Stress;

% σ_yy at top edge nodes
%sigma_yy_top = stress.syy(topEdgeNodes);
%maxStressTop = max(sigma_yy_top);
% edge nodes
topEdgeNodes = find(abs(app.model1.Mesh.Nodes(2,:) - max(app.model1.Mesh.Nodes(2,:))) < 1e-12);
%topEdgeX = app.model1.Mesh.Nodes(1, topEdgeNodes);

% approximate edge length
%edgeLength = max(topEdgeX) - min(topEdgeX); % meters

% thickness of your plate
thickness_m = 0.05; % e.g., 10 µm
edgeLength=app.PhysicallengthEditField.Value;
% cross-sectional area
A = edgeLength * thickness_m; % m²
% using average stress
sigma_avg = mean(stress.syy(topEdgeNodes)); % Pa
F_avg = sigma_avg * A; % N
fprintf('Force from average stress: %.3e N\n', F_avg);

% using maximum stress (conservative)
sigma_max = max(stress.syy(topEdgeNodes));
F_max = sigma_max * A;

app.EditField_3.Value=F_max;
        end

        % Button pushed function: ExportstressstrainButton
        function ExportstressstrainButtonPushed3(app, event)
   if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField_2.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
 E_mat   = app.E;                                  
Ep      = 0.05 * E_mat;                           % post-yield tangent
sigma_y = app.YieldstrengthEditField.Value;       % material yield stress

% Theoretical yield strain
yieldStrain_theoretical = sigma_y / E_mat;

%% ================== FEA DATA ==================
exx = app.result1.Strain.exx(:);
eyy = app.result1.Strain.eyy(:);
exy = app.result1.Strain.exy(:);

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Von Mises stress
vmStress = app.result1.VonMisesStress(:);

% Remove NaNs/Infs
validIdx = ~isnan(eqStrain) & ~isnan(vmStress) & ...
           isfinite(eqStrain) & isfinite(vmStress);

eqStrain = eqStrain(validIdx);
vmStress = vmStress(validIdx);

% Sort for smooth plotting
[eqStrain, sortIdx] = sort(eqStrain);
vmStress = vmStress(sortIdx);

maxStrain = max(eqStrain);
maxStress = max(vmStress);

%% ================== CHECK IF YIELD OCCURS ==================
hasYielded = any(eqStrain >= yieldStrain_theoretical);

if hasYielded
    yieldIdx = find(eqStrain >= yieldStrain_theoretical, 1, 'first');
    
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
    % Clamp stress if exceeding material yield
    if stress_at_yield > sigma_y
        stress_at_yield = sigma_y;
    end
else
    strain_at_yield = maxStrain;
    stress_at_yield = maxStress;
end

%% ================== BUILD CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));

if hasYielded
    % Elastic slope up to yield
    E_effective = stress_at_yield / strain_at_yield;
    
    for i = 1:nPts
        if strainLine(i) <= strain_at_yield
            % Elastic region
            stressLine(i) = E_effective * strainLine(i);
        else
            % Plastic region
            stressLine(i) = stress_at_yield + ...
                            Ep * (strainLine(i) - strain_at_yield);
        end
    end
else
    % Pure linear elastic
    E_linear = maxStress / maxStrain;
    stressLine = E_linear * strainLine;
end
 strainPercent = strainLine*100;      % %
 stressMPa= stressLine/1e6;


%plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);

    data = [strainPercent(:), stressMPa(:)];  % Columns: Strain (%) | Stress (MPa)

    % Ask user for file name and location
    [filename, pathname] = uiputfile( ...
        {'*.csv','CSV File (*.csv)'; '*.xlsx','Excel File (*.xlsx)'}, ...
        'Save Stress–Strain Data');

    if isequal(filename,0) || isequal(pathname,0)
        disp('User canceled file save.');
    else
        fullFileName = fullfile(pathname, filename);
        [~,~,ext] = fileparts(fullFileName);

        if strcmp(ext,'.csv')
            fid = fopen(fullFileName,'w');
            fprintf(fid,'Strain,Stress (MPa)\n');
            fclose(fid);
            writematrix(data, fullFileName, 'WriteMode','append');
        elseif strcmp(ext,'.xlsx')
            header = {'Strain','Stress (MPa)'};
            writecell(header, fullFileName, 'Sheet',1, 'Range','A1');
            writematrix(data, fullFileName, 'Sheet',1, 'Range','A2');
        end

        msgbox(['Stress–strain data saved to:' newline fullFileName], ...
               'Export Successful');
    end


        end

        % Button pushed function: ExportstressstrainButton_2
        function ExportstressstrainButton_2Pushed(app, event)
 if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            valStr = strtrim(app.EditField.Value);
% Check if empty
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', ...
        'Icon','warning');
    return;  % Stop further execution
end
            
  E_mat   = app.E;                                  
Ep      = 0.05 * E_mat;                           % post-yield tangent
sigma_y = app.YieldstrengthEditField.Value;       % material yield stress

% Theoretical yield strain
yieldStrain_theoretical = sigma_y / E_mat;

%% ================== FEA DATA ==================
exx = app.result2.Strain.exx(:);
eyy = app.result2.Strain.eyy(:);
exy = app.result2.Strain.exy(:);

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Von Mises stress
vmStress = app.result2.VonMisesStress(:);

% Remove NaNs/Infs
validIdx = ~isnan(eqStrain) & ~isnan(vmStress) & ...
           isfinite(eqStrain) & isfinite(vmStress);

eqStrain = eqStrain(validIdx);
vmStress = vmStress(validIdx);

% Sort for smooth plotting
[eqStrain, sortIdx] = sort(eqStrain);
vmStress = vmStress(sortIdx);

maxStrain = max(eqStrain);
maxStress = max(vmStress);

%% ================== CHECK IF YIELD OCCURS ==================
hasYielded = any(eqStrain >= yieldStrain_theoretical);

if hasYielded
    yieldIdx = find(eqStrain >= yieldStrain_theoretical, 1, 'first');
    
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
    % Clamp stress if exceeding material yield
    if stress_at_yield > sigma_y
        stress_at_yield = sigma_y;
    end
else
    strain_at_yield = maxStrain;
    stress_at_yield = maxStress;
end

%% ================== BUILD CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));

if hasYielded
    % Elastic slope up to yield
    E_effective = stress_at_yield / strain_at_yield;
    
    for i = 1:nPts
        if strainLine(i) <= strain_at_yield
            % Elastic region
            stressLine(i) = E_effective * strainLine(i);
        else
            % Plastic region
            stressLine(i) = stress_at_yield + ...
                            Ep * (strainLine(i) - strain_at_yield);
        end
    end
else
    % Pure linear elastic
    E_linear = maxStress / maxStrain;
    stressLine = E_linear * strainLine;
end
 strainPercent = strainLine*100;      % %
 stressMPa= stressLine/1e6;


%plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);

    data = [strainPercent(:), stressMPa(:)];  % Columns: Strain (%) | Stress (MPa)

    % Ask user for file name and location
    [filename, pathname] = uiputfile( ...
        {'*.csv','CSV File (*.csv)'; '*.xlsx','Excel File (*.xlsx)'}, ...
        'Save Stress–Strain Data');

    if isequal(filename,0) || isequal(pathname,0)
        disp('User canceled file save.');
    else
        fullFileName = fullfile(pathname, filename);
        [~,~,ext] = fileparts(fullFileName);

        if strcmp(ext,'.csv')
            fid = fopen(fullFileName,'w');
            fprintf(fid,'Strain (%%),Stress (MPa)\n');
            fclose(fid);
            writematrix(data, fullFileName, 'WriteMode','append');
        elseif strcmp(ext,'.xlsx')
            header = {'Strain (%)','Stress (MPa)'};
            writecell(header, fullFileName, 'Sheet',1, 'Range','A1');
            writematrix(data, fullFileName, 'Sheet',1, 'Range','A2');
        end

        msgbox(['Stress–strain data saved to:' newline fullFileName], ...
               'Export Successful');
    end

        end

        % Value changed function: YieldstrengthEditField
        function YieldstrengthEditFieldValueChanged(app, event)
            value = app.YieldstrengthEditField.Value;
            
        end

        % Callback function
        function ButtonPushed3(app, event)
            %% ================== INPUTS ==================
                    % Young's modulus (Pa)
sigma_y_material = app.YieldstrengthEditField.Value;        % PLA material yield stress (Pa)
Ep = 0.03 * app.E;                 % Plastic modulus (Pa) → 2% hardening

%% ================== STRAIN DATA ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Average equivalent strain (global response)
avgStrain = mean(eqStrain, 'all');

%% ================== STRESS DATA ==================
vmStress = app.result1.VonMisesStress;  % Pa

% Maximum von Mises stress in structure
maxVmStress = max(vmStress, [], 'all');

%% ================== FIND GEOMETRY-BASED YIELD ==================
% Yield occurs when max von Mises stress reaches material yield
if maxVmStress < sigma_y_material
    warning('Material has NOT yielded yet.');
end

% Effective (geometry-based) yield stress
geometryFactor = maxVmStress / sigma_y_material;
sigma_y_effective = sigma_y_material / geometryFactor;

% Effective yield strain
eps_y_effective = sigma_y_effective / app.E;

fprintf('Geometry amplification factor K = %.2f\n', geometryFactor);
fprintf('Effective yield stress = %.2f MPa\n', sigma_y_effective/1e6);

%% ================== BUILD BILINEAR CURVE ==================
strainLine = linspace(0, avgStrain, 300);
stressLine = zeros(size(strainLine));

for i = 1:length(strainLine)
    if strainLine(i) <= eps_y_effective
        % Elastic
        stressLine(i) = app.E * strainLine(i);
    else
        % Plastic
        stressLine(i) = sigma_y_effective + ...
                        Ep * (strainLine(i) - eps_y_effective);
    end
end

%% ================== UNIT CONVERSION ==================
strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== PLOT ==================
figure(1); clf
plot(strainPercent, stressMPa, 'b', 'LineWidth', 2)
hold on

% Yield point
plot(eps_y_effective*100, sigma_y_effective/1e6, ...
     'ro', 'MarkerFaceColor','r', 'MarkerSize',8)

xlabel('Strain (%)')
ylabel('Stress (MPa)')
title('Geometry-Based Bilinear Elastoplastic Stress–Strain Curve (PLA)')
legend('Bilinear Response','Effective Yield Point','Location','southeast')
grid on
hold off
        end

        % Callback function
        function ButtonPushed4(app, event)
            app.E = app.E;                 % Young's modulus (Pa)
sigma_y_material = 50e6;  
nSteps = length(app.result1);

eps_nom = zeros(nSteps,1);
sigma_nom = zeros(nSteps,1);
maxVM = zeros(nSteps,1);

for i = 1:nSteps

    % --- Equivalent strain (nominal / homogenized) ---
    exx = app.result1(i).Strain.exx;
    eyy = app.result1(i).Strain.eyy;
    exy = app.result1(i).Strain.exy;

    eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);
    eps_nom(i) = mean(eqStrain,'all');
A=20;
    % --- Nominal stress (from FEM reaction force / area) ---
 % Reaction force in loading direction (e.g., Y)
Fy = sum(app.result1(i).ReactionForce(:,2));
sigma_nom(i) = Fy / A;

    % --- Max von Mises stress (local) ---
    maxVM(i) = max(app.result1(i).VonMisesStress,[],'all');
end
iy = find(maxVM >= sigma_y_material, 1);

if isempty(iy)
    error('Material yield not reached in FEM simulation');
end
sigma_y_effective = sigma_nom(iy);
eps_y_effective = eps_nom(iy);

fprintf('Effective yield stress = %.2f MPa\n', sigma_y_effective/1e6);
fprintf('Effective yield strain = %.4f %%\n', eps_y_effective*100);
% Use all post-yield points
eps_post = eps_nom(iy+1:end);
sigma_post = sigma_nom(iy+1:end);

% Linear regression → mean hardening slope
p = polyfit(eps_post, sigma_post, 1);
E_geo = p(1);   % Geometry hardening modulus

fprintf('Geometry hardening modulus = %.2f MPa\n', E_geo/1e6);
fprintf('E_geo / E = %.3f\n', E_geo/app.E);
strainLine = linspace(0, max(eps_nom), 400);
stressLine = zeros(size(strainLine));

for i = 1:length(strainLine)
    if strainLine(i) <= eps_y_effective
        % Elastic
        stressLine(i) = app.E * strainLine(i);
    else
        % Geometry-controlled plastic hardening
        stressLine(i) = sigma_y_effective + ...
                        E_geo * (strainLine(i) - eps_y_effective);
    end
end
figure(1); clf

% FEM curve
plot(eps_nom*100, sigma_nom/1e6, 'k.--', 'LineWidth', 1.2)
hold on

% Bilinear curve
plot(strainLine*100, stressLine/1e6, 'b', 'LineWidth', 2)

% Yield point
plot(eps_y_effective*100, sigma_y_effective/1e6, ...
     'ro','MarkerFaceColor','r','MarkerSize',8)

xlabel('Strain (%)')
ylabel('Stress (MPa)')
title('Geometry-Based Bilinear Stress–Strain Curve (PLA Structure)')
legend('FEM response','Bilinear fit','Effective yield','Location','southeast')
grid on
hold off
        end

        % Button pushed function: EnterButton_4
        function EnterButton_4Pushed(app, event)
            if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            app.EditField_2.Value='';
           app.EditField.Value='';
           app.PredictiontimesecEditField.Value=0;
           app.FEAtimesecEditField.Value=0;
            
           app.EditField_2.Value='';
           app.EditField.Value='';
           app.PredictiontimesecEditField.Value=0;
           app.FEAtimesecEditField.Value=0;
            
            d = uiprogressdlg(app.UIFigure, ...
    'Title','Select ROI', ...
    'Message','ROI has been selected successfully.', ...
    'Indeterminate','on', ...
    'Cancelable','off');


           % Get original binary image
originalImage = app.binaryimage1;
[height, width] = size(originalImage);

% Initialize padded image as original
app.paddedImage = originalImage;

% Select ROI type
switch app.SelectROIDropDown.Value
    case 'Black'
        % Black region = 0
        regionMask  = (originalImage == 0);
        paddingColor = 0;

    case 'White'
        % White region = 1
        regionMask  = (originalImage == 1);
        paddingColor = 1;

    otherwise
        disp('No valid ROI selected.');
        imshow(originalImage);
        return;
end

% Find rows containing region pixels (ROBUST for diagonal shapes)
regionPixelRows = find(any(regionMask, 2));

% If no region found
if isempty(regionPixelRows)
    disp(['No ' app.SelectROIDropDown.Value ' pixels found in the image.']);
    imshow(originalImage);
    return;
end

% Find original top and bottom rows
topRowRaw    = min(regionPixelRows);
bottomRowRaw = max(regionPixelRows);

% Tolerance (ignore extreme rows)
tolerance = 3;

% Adjusted top & bottom rows (safe-guarded)
topRow    = min(regionPixelRows(regionPixelRows >= topRowRaw + tolerance));
bottomRow = max(regionPixelRows(regionPixelRows <= bottomRowRaw - tolerance));

% Fallback if tolerance removes everything
if isempty(topRow) || isempty(bottomRow)
    topRow    = topRowRaw;
    bottomRow = bottomRowRaw;
end

% Padding size
paddingSize = 10;

% Add padding ABOVE (with tolerance)
startRow = max(1, topRow - paddingSize);
app.paddedImage(startRow:topRow-1, :) = paddingColor;

% Add padding BELOW (with tolerance)
endRow = min(height, bottomRow + paddingSize);
app.paddedImage(bottomRow+1:endRow, :) = paddingColor;

% Display result
imshow(app.paddedImage);
        end

        % Value changed function: SelectROIDropDown
        function SelectROIDropDownValueChanged(app, event)
            
            value = app.SelectROIDropDown.Value;
            
        end

        % Button pushed function: TensionButton
        function TensionButtonPushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end                 
            app.result2=[];
        app.model2=[];
        close all
        app.EditField.Value='';


    % Simulate some processing
if app.YoungsmodulusEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Young''s Modulus value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end  


if app.YieldstrengthEditField.Value == 0
    uialert(app.UIFigure, ...
        'Please enter a valid Yield strength value greater than 0.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop further execution
end


nu = app.PoissonsratioEditField.Value;

% Validate input
if isnan(nu) || nu <= 0 || nu >= 0.5
    uialert(app.UIFigure, ...
        'Please enter a valid Poisson''s ratio between 0 and 0.5.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
end
 if app.YdisplacementEditField.Value == 0

% Validate input
    uialert(app.UIFigure, ...
        'Please enter a valid numeric value for Y displacement.', ...
        'Invalid Input', ...
        'Icon','warning');
    return;  % Stop execution
 end
  d = uiprogressdlg(app.UIFigure, ...
    'Title','Image-based FEA processing', ...
    'Message','Processing...', ...
    'Indeterminate','on', ...
    'Cancelable','off');
  
            % Material properties
            
             tic;
    
    % Simulate some processing
   
app.E =  app.YoungsmodulusEditField.Value;           % Young's modulus (Pa)

 img = app.paddedImage;

% Process based on ROI selection
switch app.SelectROIDropDown.Value
    case 'White'
        % White region = 1
        app.binaryimage = (img == 1);

    case 'Black'
        % Black region = 0
        app.binaryimage = (img == 0);

    otherwise
        uialert(app.UIFigure, ...
        'Please select region of interest.', ...
        'Invalid ROI', ...
        'Icon','warning');
        return;
end

% Get row and column indices of selected region
[row, col] = find(app.binaryimage);

%% Step 3: Create a Grid of Points Inside the White Region
gridSpacing = 1.8; % Grid spacing (in pixels)
minX = min(col); maxX = max(col);
minY = min(row); maxY = max(row);
[xGrid, yGrid] = meshgrid(minX:gridSpacing:maxX, minY:gridSpacing:maxY);

xGrid = xGrid(:); yGrid = yGrid(:);

%% Step 4: Masking - Keep Only Points Inside the White Region
validIndices = app.binaryimage(sub2ind(size(app.binaryimage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices); validY = yGrid(validIndices);


%% Step 5: Delaunay Triangulation
app.triangles = delaunay(validX, validY);

%% Step 6: Remove Triangles That Cross Black Regions
validTriangles = [];
for i = 1:size(app.triangles, 1)
    triangleVertices = [validX(app.triangles(i, :)), validY(app.triangles(i, :))];
    if ~anyEdgeCrossesBlackRegion(triangleVertices, app.binaryimage)
        validTriangles = [validTriangles; app.triangles(i, :)];
    end
end

%% Step 7: Flip Y-coordinates to Match Cartesian System
validY = max(validY) - validY;

mesh.Vertices = [validX, max(validY) - validY];
%mesh.Vertices = [validX, max(validY) - validY] * 1e-3; % Convert pixels to meters (example)
mesh.Elements = validTriangles;

Vertices = mesh.Vertices;
elements = mesh.Elements;

nodes = Vertices';  % 2xN
elements = elements'; % 3xM

% Fix element orientation (counter-clockwise)
for i = 1:size(elements, 2)
    v1 = nodes(:, elements(1,i));
    v2 = nodes(:, elements(2,i));
    v3 = nodes(:, elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end

switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
       app.model2 = createpde('structural','static-planestress');

    otherwise
       app.model2 = createpde('structural','static-planestrain');  
end


% Create PDE model
%app.model1 = createpde('structural','static-planestrain');
geometryFromMesh(app.model2, nodes, elements);
%generateMesh(app.model1, 'Hmax', 0.5);
%generateMesh(app.model1, 'Hmax', 0.1);
% Define material properties
P= app.PoissonsratioEditField.Value;
structuralProperties(app.model2, 'YoungsModulus', app.E, 'PoissonsRatio', P);

% Find top and bottom edges for BC (nearestEdge function or manual)
vertices = app.model2.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));

topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];

topEdgeID = nearestEdge(app.model2.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model2.Geometry, bottomEdgePoint);
%disp=app.YdisplacementEditField.Value;

% Apply displacement BC at top (yield strain displacement)
yield_disp_m =  -(app.YdisplacementEditField.Value/100) * (maxY - minY);% approximate displacement at yield in meters
structuralBC(app.model2, 'Edge', topEdgeID, 'Displacement', [0, yield_disp_m]); % downward displacement
structuralBC(app.model2, 'Edge', bottomEdgeID, 'YDisplacement', 0);

% Solve model
app.result2 = solve(app.model2);

pause(90);


toc
%% ================== EXTRACT DATA ==================
% Get von Mises stress data
exx = app.result2.Strain.exx;
eyy = app.result2.Strain.eyy;
exy = app.result2.Strain.exy;

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Average equivalent strain (global)
avgStrain = mean(eqStrain, 'all');
% Maximum equivalent strain
maxStrainValue = max(eqStrain(:));

%% ================== STRESS DATA ==================
vmStress = app.result2.VonMisesStress;   % Pa

% Average equivalent stress
avgStress = mean(vmStress, 'all');
% Maximum von Mises stress
maxStressValue = max(vmStress(:));

%% ================== GEOMETRY-BASED ELASTIC MODULUS ==================
% Use AVERAGE values to calculate effective modulus
E_eff = avgStress / avgStrain;           % Pa

%% ================== BUILD ELASTIC CURVE ==================
nPts = 300;
% Use MAX strain for plotting to show full range
strainLine = linspace(0, maxStrainValue, nPts);   % dimensionless
stressLine = E_eff * strainLine;             % Pa

%% ================== UNIT CONVERSION ==================
strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== TIMING ==================
elapsedTime = toc;
app.FEAtimesecEditField.Value= elapsedTime;

%% ================== MAX VALUES ==================
% Use ACTUAL maximum values from simulation
% NOT the endpoint of the calculated curve
maxStrainPercent = maxStrainValue * 100;  % Actual max from simulation
maxStressMPa = maxStressValue / 1e6;      % Actual max from simulation

%% ================== MAX VALUES ==================
maxStrainValue = strainLine(end);  % Use end of strainLine, not max from simulation
maxStressValue = stressLine(end);
app.EditField.Value = sprintf( ...
    'Max strain = %.3f %%\nMax stress = %.2f MPa', ...
    maxStrainValue*100, maxStressValue/1e6 );

        end

        % Callback function
        function ButtonPushed5(app, event)
            %% ================== INITIAL CHECKS ==================
close all;

% ROI selection check
if strcmp(app.SelectROIDropDown.Value,'Select options')
    uialert(app.UIFigure,'Please select the ROI.','No valid ROI');
    return;
end

if isempty(app.binaryimage) || ~any(app.binaryimage(:) == 0)
    uialert(app.UIFigure,'Please select the ROI.','No valid ROI');
    return;
end

% Material properties check
if app.YoungsmodulusEditField.Value <= 0
    uialert(app.UIFigure,'Please enter a valid Young''s Modulus value > 0.','Invalid Input','Icon','warning');
    return;
end
if app.YieldstrengthEditField.Value <= 0
    uialert(app.UIFigure,'Please enter a valid Yield strength value > 0.','Invalid Input','Icon','warning');
    return;
end

% Poisson ratio check
nu = app.PoissonsratioEditField.Value;
if isnan(nu) || nu <= 0 || nu >= 0.5
    uialert(app.UIFigure,'Please enter a valid Poisson''s ratio between 0 and 0.5.','Invalid Input','Icon','warning');
    return;
end

% Displacement check
if app.YdisplacementEditField.Value == 0
    uialert(app.UIFigure,'Please enter a valid numeric value for Y displacement.','Invalid Input','Icon','warning');
    return;
end

%% ================== PROGRESS DIALOG ==================
d = uiprogressdlg(app.UIFigure, 'Title','AI-BioMech Prediction', ...
    'Message','Processing...', 'Indeterminate','on','Cancelable','off');
tic;

%% ================== MATERIAL PROPERTIES ==================
app.E = app.YoungsmodulusEditField.Value;            % Young's modulus (Pa)
sigma_y_material = app.YieldstrengthEditField.Value; % Material yield stress (Pa)
Ep = 0.02 * app.E;                                  % Plastic modulus → 2% hardening
P = app.PoissonsratioEditField.Value;           % Poisson's ratio

%% ================== IMAGE AND MESH PROCESSING ==================
app.binaryimage = ~(app.paddedImage);
[row, col] = find(app.binaryimage == 1);

% Grid points inside white region
gridSpacing = 2; % pixels
[xGrid, yGrid] = meshgrid(min(col):gridSpacing:max(col), min(row):gridSpacing:max(row));
xGrid = xGrid(:); yGrid = yGrid(:);

% Keep only points inside white region
validIndices = app.binaryimage(sub2ind(size(app.binaryimage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices); validY = yGrid(validIndices);

% Delaunay triangulation
triangles = delaunay(validX, validY);

% Remove triangles crossing black regions
validTriangles = [];
for i = 1:size(triangles,1)
    verts = [validX(triangles(i,:)), validY(triangles(i,:))];
    if ~anyEdgeCrossesBlackRegion(verts, app.binaryimage)
        validTriangles = [validTriangles; triangles(i,:)];
    end
end

% Flip Y-coordinates to match Cartesian
validY = max(validY) - validY;
mesh.Vertices = [validX, validY];
mesh.Elements = validTriangles;

Vertices = mesh.Vertices;
Elements = mesh.Elements;

nodes = Vertices';
elements = Elements';

% Fix element orientation
for i = 1:size(elements,2)
    v1 = nodes(:,elements(1,i));
    v2 = nodes(:,elements(2,i));
    v3 = nodes(:,elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end

%% ================== PDE MODEL ==================
switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
        app.model1 = createpde('structural','static-planestress');
    otherwise
        app.model1 = createpde('structural','static-planestrain');  
end

geometryFromMesh(app.model1, nodes, elements);
structuralProperties(app.model1, 'YoungsModulus', app.E, 'PoissonsRatio', P);

% Identify top/bottom edges
vertices = app.model1.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));
topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];
topEdgeID = nearestEdge(app.model1.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model1.Geometry, bottomEdgePoint);


% Apply displacement BC
yield_disp_m = -(app.YdisplacementEditField.Value)* (maxY - minY);
structuralBC(app.model1, 'Edge', topEdgeID, 'Displacement', [0, yield_disp_m]);
structuralBC(app.model1, 'Edge', bottomEdgeID, 'YDisplacement', 0);

%% ================== SOLVE ==================
try
    app.result1 = solve(app.model1);
catch
    uialert(app.UIFigure,'Invalid ROI or disconnected patches. Use high-resolution image.','Prediction Failed','Icon','error');
    close(d);
    return;
end
pause(2); close(d);

%% ================== STRAIN AND STRESS ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

% Equivalent von Mises strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);
avgStrainMax = mean(eqStrain, 'all');

% Compute top-bottom engineering strain (matches displacement input)
topNodes = find(vertices(:,2) == maxY);
bottomNodes = find(vertices(:,2) == minY);
u_top = mean(app.result1.Displacement.uy(topNodes));
u_bottom = mean(app.result1.Displacement.uy(bottomNodes));
appliedStrain = abs(u_top - u_bottom) / (maxY - minY);  % dimensionless

%% ================== GEOMETRY-BASED YIELD ==================
vmStress = app.result1.VonMisesStress;
maxVmStress = max(vmStress, [], 'all');

if maxVmStress < sigma_y_material
    warning('Material has NOT yielded yet.');
end

geometryFactor = maxVmStress / sigma_y_material;
sigma_y_effective = sigma_y_material / geometryFactor;
eps_y_effective = sigma_y_effective / app.E;

%% ================== BILINEAR STRESS-STRAIN CURVE ==================
strainLine = linspace(0, appliedStrain, 300);
stressLine = zeros(size(strainLine));

for i = 1:length(strainLine)
    if strainLine(i) <= eps_y_effective
        stressLine(i) =app.E * strainLine(i);
    else
        stressLine(i) = sigma_y_effective + Ep * (strainLine(i)-eps_y_effective);
    end
end

strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== UPDATE EDIT FIELD ==================
app.EditField_2.Value = sprintf('Max strain = %.3f %%\nMax stress = %.2f MPa', ...
    strainPercent(end), max(stressMPa));

%% ================== PLOT ==================
figure(1); clf;
ax = gca;
ax.Color = [0.5 1 0];
xlim([0 max(strainPercent)*1.1])
ylim([0 max(stressMPa)*1.1])
plot(strainPercent, stressMPa, 'b', 'LineWidth', 2); hold on;

% Plot yield point
if max(stressLine) >= sigma_y_effective
    plot(eps_y_effective*100, sigma_y_effective/1e6, 'ro', 'MarkerFaceColor','r', 'MarkerSize',8)
    legend({'Bilinear Response','Effective Yield Point'},'Location','southeast');
else
    legend({'Elastic Response (No Yielding)'},'Location','southeast');
end

xlabel('Strain (%)'); ylabel('Stress (MPa)');
title('AI-BioMech Predicted Stress–Strain Response');
grid on; hold off;

%% ================== RECORD ELAPSED TIME ==================
elapsedTime = toc;
app.PredictiontimesecEditField.Value = elapsedTime;
        end

        % Callback function
        function ButtonPushed6(app, event)
            %% ================== INITIAL CHECKS ==================
close all;

% ROI selection check
if strcmp(app.SelectROIDropDown.Value,'Select options')
    uialert(app.UIFigure,'Please select the ROI.','No valid ROI');
    return;
end

if isempty(app.binaryimage) || ~any(app.binaryimage(:) == 0)
    uialert(app.UIFigure,'Please select the ROI.','No valid ROI');
    return;
end

% Material properties check
if app.YoungsmodulusEditField.Value <= 0
    uialert(app.UIFigure,'Please enter a valid Young''s Modulus value > 0.','Invalid Input','Icon','warning');
    return;
end
if app.YieldstrengthEditField.Value <= 0
    uialert(app.UIFigure,'Please enter a valid Yield strength value > 0.','Invalid Input','Icon','warning');
    return;
end

% Poisson ratio check
nu = app.PoissonsratioEditField.Value;
if isnan(nu) || nu <= 0 || nu >= 0.5
    uialert(app.UIFigure,'Please enter a valid Poisson''s ratio between 0 and 0.5.','Invalid Input','Icon','warning');
    return;
end

% Displacement check
if app.YdisplacementEditField.Value == 0
    uialert(app.UIFigure,'Please enter a valid numeric value for Y displacement.','Invalid Input','Icon','warning');
    return;
end

%% ================== PROGRESS DIALOG ==================
d = uiprogressdlg(app.UIFigure, 'Title','AI-BioMech Prediction', ...
    'Message','Processing...', 'Indeterminate','on','Cancelable','off');
tic;

%% ================== MATERIAL PROPERTIES ==================
E = app.YoungsmodulusEditField.Value;             % Young's modulus (Pa)
sigma_y_material = app.YieldstrengthEditField.Value; % Yield stress (Pa)
Ep = 0.02 * E;                                    % Plastic modulus → 2% hardening
P = app.PoissonsratioEditField.Value;             % Poisson ratio

%% ================== IMAGE AND MESH PROCESSING ==================
app.binaryimage = ~(app.paddedImage);
[row, col] = find(app.binaryimage == 1);

% Grid points inside white region
gridSpacing = 2; % pixels
[xGrid, yGrid] = meshgrid(min(col):gridSpacing:max(col), min(row):gridSpacing:max(row));
xGrid = xGrid(:); yGrid = yGrid(:);

% Keep only points inside white region
validIndices = app.binaryimage(sub2ind(size(app.binaryimage), round(yGrid), round(xGrid))) == 1;
validX = xGrid(validIndices); validY = yGrid(validIndices);

% Delaunay triangulation
triangles = delaunay(validX, validY);

% Remove triangles crossing black regions
validTriangles = [];
for i = 1:size(triangles,1)
    verts = [validX(triangles(i,:)), validY(triangles(i,:))];
    if ~anyEdgeCrossesBlackRegion(verts, app.binaryimage)
        validTriangles = [validTriangles; triangles(i,:)];
    end
end

% Flip Y-coordinates to match Cartesian
validY = max(validY) - validY;
mesh.Vertices = [validX, validY];
mesh.Elements = validTriangles;

Vertices = mesh.Vertices;
Elements = mesh.Elements;

nodes = Vertices';
elements = Elements';

% Fix element orientation (counter-clockwise)
for i = 1:size(elements,2)
    v1 = nodes(:,elements(1,i));
    v2 = nodes(:,elements(2,i));
    v3 = nodes(:,elements(3,i));
    signedArea = (v2(1)-v1(1))*(v3(2)-v1(2)) - (v2(2)-v1(2))*(v3(1)-v1(1));
    if signedArea < 0
        elements([2 3],i) = elements([3 2],i);
    end
end

%% ================== PDE MODEL ==================
switch app.SelectoptionDropDown.Value
    case 'Plane-stress'
        app.model1 = createpde('structural','static-planestress');
    otherwise
        app.model1 = createpde('structural','static-planestrain');  
end

geometryFromMesh(app.model1, nodes, elements);
structuralProperties(app.model1, 'YoungsModulus', E, 'PoissonsRatio', P);

% Identify top/bottom edges
vertices = app.model1.Geometry.Vertices;
maxY = max(vertices(:,2));
minY = min(vertices(:,2));
topEdgePoint = [mean(vertices(vertices(:,2) == maxY,1)), maxY];
bottomEdgePoint = [mean(vertices(vertices(:,2) == minY,1)), minY];
topEdgeID = nearestEdge(app.model1.Geometry, topEdgePoint);
bottomEdgeID = nearestEdge(app.model1.Geometry, bottomEdgePoint);

% Apply displacement BC (pixels input)
yield_disp_m = -(app.YdisplacementEditField.Value / 100) * (maxY - minY); % % of height
structuralBC(app.model1, 'Edge', topEdgeID, 'Displacement', [0, yield_disp_m]);
structuralBC(app.model1, 'Edge', bottomEdgeID, 'YDisplacement', 0);

%% ================== SOLVE PDE ==================
try
    app.result1 = solve(app.model1);
catch
    uialert(app.UIFigure,'Invalid ROI or disconnected patches.','Prediction Failed','Icon','error');
    close(d);
    return;
end
pause(2); close(d);

%% ================== STRAIN AND STRESS ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

% Equivalent von Mises strain field
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);
avgStrain = mean(eqStrain, 'all');
maxStrainFEM = max(eqStrain, [], 'all');

vmStress = app.result1.VonMisesStress;
maxVmStress = max(vmStress, [], 'all');

%% ================== GEOMETRY-BASED YIELD ==================
geometryFactor = maxVmStress / sigma_y_material;
sigma_y_effective = sigma_y_material / geometryFactor;
eps_y_effective = sigma_y_effective / E;

fprintf('Geometry factor = %.2f\n', geometryFactor);
fprintf('Effective yield stress = %.2f MPa\n', sigma_y_effective/1e6);

%% ================== BUILD BILINEAR CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrainFEM, nPts);
stressLine = zeros(size(strainLine));

for i = 1:nPts
    if strainLine(i) <= eps_y_effective
        stressLine(i) = E * strainLine(i); % elastic
    else
        stressLine(i) = sigma_y_effective + Ep*(strainLine(i)-eps_y_effective); % plastic
    end
end

strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== UPDATE GUI ==================
app.EditField_2.Value = sprintf('Max strain = %.3f %%\nYield stress = %.2f MPa', ...
    strainPercent(end), max(stressMPa));

%% ================== PLOT ==================
figure(1); clf
ax = gca;
ax.Color = [0.5 1 0];
xlim([0 max(strainPercent)*1.1])
ylim([0 max(stressMPa)*1.1])
plot(strainPercent, stressMPa, 'b', 'LineWidth', 2); hold on;
plot(eps_y_effective*100, sigma_y_effective/1e6, 'ro', 'MarkerFaceColor','r', 'MarkerSize',8);
xlabel('Strain (%)'); ylabel('Stress (MPa)');
title('AI-BioMech Predicted Stress–Strain Response');
legend({'Bilinear response','Yield point'}, 'Location','southeast');
grid on; hold off;

%% ================== TIMING ==================
elapsedTime = toc;
app.PredictiontimesecEditField.Value = elapsedTime;

        end

        % Button pushed function: CleanimageButton
        function CleanimageButtonPushed(app, event)
             if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
            % ================= INITIAL SETUP =================
if isempty(app.img)
    % Show alert and stop execution
    uialert(app.UIFigure, 'Please browse and load an image first.', 'No Image Found');
    return;  % Exit immediately
end
app.binaryimage1=imresize(app.binaryimage1,[1024 1024]);
originalImg = app.binaryimage1 > 0;   % FORCE LOGICAL
baseImage   = originalImg;            
workingImg  = originalImg;            

% Create fullscreen figure
screenSize = get(0,'ScreenSize');
app.f = figure('Name','Image Editor','Position',screenSize,...
    'NumberTitle','off','MenuBar','none','ToolBar','none',...
    'Color',[0.78 0.82 0.75]);

ax = axes('Parent',app.f,'Position',[0.05 0.1 0.6 0.8]);
imshow(workingImg,'Parent',ax);

% ================= CONTROL PANEL =================
controlPanel = uipanel(app.f,'Position',[0.68 0.1 0.3 0.8],...
    'Title','Cleaning options','FontWeight','bold','FontSize',14,...
    'FontName','Verdana','BackgroundColor',[0.78 0.82 0.75],...
    'ForegroundColor',[0.05 0.17 0.31]);

% Define common button styling
buttonBgColor = [0.05 0.17 0.31];
buttonTextColor = [1 1 1];
fontName = 'Verdana';
fontSize = 12;

% Calculate positions
startY = 600;

% PIXEL COUNT DISPLAY with percentages (MOVED TO TOP)
app.pixelCountText = uicontrol(controlPanel, 'Style', 'text', ...
    'Position', [30 startY-60 300 60], 'String', 'White pixels: 0 (0%)   Black pixels: 0 (0%)   Total: 0', ...
    'HorizontalAlignment', 'center', 'FontSize', 11, ...
    'FontName', fontName, 'ForegroundColor', [0.05 0.17 0.31], ...
    'BackgroundColor', [0.78 0.82 0.75], 'FontWeight', 'bold');

% 1. ERASER CONTROLS
uicontrol(controlPanel, 'Style', 'text', 'Position', [30 startY-140 220 35], ...
    'String', 'ERASER SIZE', 'FontWeight', 'bold', 'FontSize', 14, ...
    'HorizontalAlignment', 'left', 'FontName', fontName, ...
    'ForegroundColor', [0.05 0.17 0.31], 'BackgroundColor', [0.78 0.82 0.75]);

app.eraserSizeSlider = uicontrol(controlPanel, 'Style', 'slider', ...
    'Position', [30 startY-160 220 30], 'Min', 1, 'Max', 50, 'Value', 15, ...
    'BackgroundColor', [0.88 0.92 0.88]);

app.eraserSizeText = uicontrol(controlPanel, 'Style', 'text', ...
    'Position', [30 startY-180 220 30], 'String', 'Size: 15 px', ...
    'HorizontalAlignment', 'center', 'FontSize', fontSize, ...
    'FontName', fontName, 'ForegroundColor', [0.05 0.17 0.31], ...
    'BackgroundColor', [0.78 0.82 0.75]);

% 2. EROSION CONTROLS
uicontrol(controlPanel, 'Style', 'text', 'Position', [30 startY-240 220 35], ...
    'String', 'EROSION', 'FontWeight', 'bold', 'FontSize', 14, ...
    'HorizontalAlignment', 'left', 'FontName', fontName, ...
    'ForegroundColor', [0.05 0.17 0.31], 'BackgroundColor', [0.78 0.82 0.75]);

app.erosionSlider = uicontrol(controlPanel, 'Style', 'slider', ...
    'Position', [30 startY-260 220 30], 'Min', 0, 'Max', 10, 'Value', 0, ...
    'BackgroundColor', [0.88 0.92 0.88]);

app.erosionText = uicontrol(controlPanel, 'Style', 'text', ...
    'Position', [30 startY-280 220 30], 'String', 'Erosion: 0 px', ...
    'HorizontalAlignment', 'center', 'FontSize', fontSize, ...
    'FontName', fontName, 'ForegroundColor', [0.05 0.17 0.31], ...
    'BackgroundColor', [0.78 0.82 0.75]);

% 3. DILATION CONTROLS
uicontrol(controlPanel, 'Style', 'text', 'Position', [30 startY-340 220 35], ...
    'String', 'DILATION', 'FontWeight', 'bold', 'FontSize', 14, ...
    'HorizontalAlignment', 'left', 'FontName', fontName, ...
    'ForegroundColor', [0.05 0.17 0.31], 'BackgroundColor', [0.78 0.82 0.75]);

app.dilationSlider = uicontrol(controlPanel, 'Style', 'slider', ...
    'Position', [30 startY-360 220 30], 'Min', 0, 'Max', 10, 'Value', 0, ...
    'BackgroundColor', [0.88 0.92 0.88]);

app.dilationText = uicontrol(controlPanel, 'Style', 'text', ...
    'Position', [30 startY-380 220 30], 'String', 'Dilation: 0 px', ...
    'HorizontalAlignment', 'center', 'FontSize', fontSize, ...
    'FontName', fontName, 'ForegroundColor', [0.05 0.17 0.31], ...
    'BackgroundColor', [0.78 0.82 0.75]);

% UNDO/REDO BUTTONS
undoBtn = uicontrol(controlPanel, 'Style', 'pushbutton', ...
    'Position', [30 startY-460 100 45], 'String', 'UNDO', ...
    'BackgroundColor', buttonBgColor, 'ForegroundColor', buttonTextColor, ...
    'FontSize', fontSize, 'FontName', fontName, 'FontWeight', 'bold');

redoBtn = uicontrol(controlPanel, 'Style', 'pushbutton', ...
    'Position', [150 startY-460 100 45], 'String', 'REDO', ...
    'BackgroundColor', buttonBgColor, 'ForegroundColor', buttonTextColor, ...
    'FontSize', fontSize, 'FontName', fontName, 'FontWeight', 'bold');

% SAVE BUTTON
saveBtn = uicontrol(controlPanel, 'Style', 'pushbutton', ...
    'Position', [30 startY-540 220 70], 'String', 'SAVE & EXIT', ...
    'FontWeight', 'bold', 'FontSize', 16, 'FontName', fontName, ...
    'BackgroundColor', buttonBgColor, 'ForegroundColor', buttonTextColor);

% ================= INITIALIZE VARIABLES =================
clickCount = 0;
done = false;
currentEraserSize = 15;

% Create UNDO/REDO stacks
undoStack = {};
undoStack{1} = baseImage;
currentIndex = 1;
maxUndos = 20;

% ================= HELPER FUNCTIONS =================
function updateEraserSize()
    currentEraserSize = round(get(app.eraserSizeSlider, 'Value'));
    set(app.eraserSizeText, 'String', sprintf('Size: %d px', currentEraserSize));
end

function saveState()
    % Save current state to undo stack
    if length(undoStack) >= maxUndos
        undoStack = undoStack(2:end);
        currentIndex = currentIndex - 1;
    end
    
    undoStack{end+1} = baseImage;
    currentIndex = length(undoStack);
end

function performUndo()
    if currentIndex > 1
        currentIndex = currentIndex - 1;
        baseImage = undoStack{currentIndex};
        updateDisplay();
        disp('Undo: Reverted last eraser action');
    else
        disp('Cannot undo further');
    end
end

function performRedo()
    if currentIndex < length(undoStack)
        currentIndex = currentIndex + 1;
        baseImage = undoStack{currentIndex};
        updateDisplay();
        disp('Redo: Restored next eraser action');
    else
        disp('Cannot redo further');
    end
end

function updateDisplay()
    % Get current slider values
    erosionVal = round(get(app.erosionSlider, 'Value'));
    dilationVal = round(get(app.dilationSlider, 'Value'));
    
    % Update display texts
    set(app.erosionText, 'String', sprintf('Erosion: %d px', erosionVal));
    set(app.dilationText, 'String', sprintf('Dilation: %d px', dilationVal));
    
    % Start from base image
    tempImg = baseImage;
    
    % Apply morphology
    if erosionVal > 0
        tempImg = imerode(tempImg, strel('disk', erosionVal));
    end
    
    if dilationVal > 0
        tempImg = imdilate(tempImg, strel('disk', dilationVal));
    end
    
    % Update working image and display
    workingImg = tempImg;
    imshow(workingImg, 'Parent', ax);
    
    % Calculate pixel counts and percentages
    totalPixels = numel(workingImg);
    whitePixels = sum(workingImg(:));
    blackPixels = totalPixels - whitePixels;
    
    whitePercent = (whitePixels / totalPixels) * 100;
    blackPercent = (blackPixels / totalPixels) * 100;
    
    % Update pixel count display (now at top)
    set(app.pixelCountText, 'String', ...
        sprintf('White pixels: %d (%.1f%%)   Black pixels: %d (%.1f%%)   Total: %d', ...
        whitePixels, whitePercent, blackPixels, blackPercent, totalPixels));
end

function saveAndExit()
    app.binaryimage1 = workingImg;
    done = true;
end

% ================= SETUP CALLBACKS =================
set(app.eraserSizeSlider, 'Callback', @(~,~) updateEraserSize());
set(app.erosionSlider, 'Callback', @(~,~) updateDisplay());
set(app.dilationSlider, 'Callback', @(~,~) updateDisplay());
set(undoBtn, 'Callback', @(~,~) performUndo());
set(redoBtn, 'Callback', @(~,~) performRedo());
set(saveBtn, 'Callback', @(~,~) saveAndExit());

% Initial display update
updateDisplay();

% ================= MAIN LOOP =================
while ~done && ishandle(app.f)
    try
        [x, y, button] = ginput(1);
        
        if isempty(button)
            continue;
        end
        
        if button == 1  % LEFT CLICK - ERASER
            x = round(x);
            y = round(y);
            
            [h, w] = size(baseImage);
            
            if x >= 1 && x <= w && y >= 1 && y <= h
                % Save state before editing
                saveState();
                
                clickCount = clickCount + 1;
                
                % Get eraser size
                eraserSize = round(get(app.eraserSizeSlider, 'Value'));
                halfSize = floor(eraserSize/2);
                
                % Calculate eraser bounds
                x1 = max(1, x - halfSize);
                x2 = min(w, x + halfSize);
                y1 = max(1, y - halfSize);
                y2 = min(h, y + halfSize);
                
                % SIMPLE AND EFFECTIVE ERASER LOGIC - BIT INVERSION
                baseImage(y1:y2, x1:x2) = ~baseImage(y1:y2, x1:x2);
                
                % Update display
                updateDisplay();
                
                % Visual feedback
                rectangle('Position', [x1, y1, x2-x1, y2-y1], ...
                    'EdgeColor', 'r', 'LineWidth', 1.5, 'LineStyle', '--');
                pause(0.1);
                imshow(workingImg, 'Parent', ax);
            end
            
        elseif button == 26  % Ctrl+Z (UNDO)
            performUndo();
            
        elseif button == 25  % Ctrl+Y (REDO)
            performRedo();
            
        elseif button == 13  % ENTER - Save and exit
            saveAndExit();
            
        elseif button == 27  % ESC - Cancel
            choice = questdlg('Exit editor?', 'Confirm Exit', ...
                'Save and Exit', 'Cancel and Discard', 'Continue Editing', 'Continue Editing');
            
            if strcmp(choice, 'Save and Exit')
                saveAndExit();
            elseif strcmp(choice, 'Cancel and Discard')
                app.binaryimage1 = originalImg;
                disp('Editing cancelled - original image restored');
                if ishandle(app.f)
                    close(app.f);
                end
                return;
            end
            
        elseif button == 43  % '+' key - Increase eraser
            currentVal = get(app.eraserSizeSlider, 'Value');
            newVal = min(currentVal + 2, 50);
            set(app.eraserSizeSlider, 'Value', newVal);
            updateEraserSize();
            
        elseif button == 45  % '-' key - Decrease eraser
            currentVal = get(app.eraserSizeSlider, 'Value');
            newVal = max(currentVal - 2, 1);
            set(app.eraserSizeSlider, 'Value', newVal);
            updateEraserSize();
        end
        
    catch ME
        disp(['Error: ', ME.message]);
        done = true;
    end
end

% ================= CLEANUP =================
if ishandle(app.f)
    close(app.f);
end
app.binaryimage1=imresize(app.binaryimage1,[512 512]);
% Display final image
imshow(app.binaryimage1, 'Parent', app.UIAxes2);
drawnow;

% Success message
msg = msgbox('Clean image saved!', 'Success', 'help');
pause(1);
if ishandle(msg)
    close(msg);
end
        end

        % Callback function
        function ButtonPushed7(app, event)
%% ================== INPUTS ==================
%% ================== MATERIAL PROPERTIES ==================
E       = app.E;                                   % Young's modulus (Pa)
sigma_y = app.YieldstrengthEditField.Value;        % Yield stress (Pa)
Ep      = 0.02 * E;                                % Plastic modulus (2% hardening)

%% ================== STRAIN DATA ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

% Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

%% ================== STRESS DATA ==================
vmStress = app.result1.VonMisesStress;

%% ================== GLOBAL RESPONSE ==================
globalStrain = mean(eqStrain(:),'omitnan');
globalStress = mean(vmStress(:),'omitnan');

%% ================== LOCAL RESPONSE (CRITICAL POINT) ==================
[localStress, idx] = max(vmStress(:));   % max von Mises stress
localStrain = eqStrain(idx);             % corresponding strain

%% ================== KEY FIX: STRESS CONCENTRATION FACTOR ==================
% Local point experiences higher stress due to geometry/loading
if globalStress > 0
    Kt = localStress / globalStress;      % Stress concentration factor
else
    Kt = 1;
end

%% ================== REPORT VALUES ==================
fprintf('GLOBAL: Stress = %.2f MPa | Strain = %.4f %%\n', ...
        globalStress/1e6, globalStrain*100);
fprintf('LOCAL : Stress = %.2f MPa | Strain = %.4f %%\n', ...
        localStress/1e6, localStrain*100);
fprintf('Stress Concentration Factor Kt = %.2f\n', Kt);

%% ================== YIELD PARAMETERS ==================
eps_y = sigma_y / E;   % Yield strain

global_yielding = globalStress >= sigma_y;
local_yielding  = localStress  >= sigma_y;

%% ================== STRAIN RANGE FOR CURVES ==================
max_strain = max([globalStrain, localStrain, eps_y]) * 1.5;
strainLine = linspace(0, max_strain, 400);

%% ================== GLOBAL STRESS–STRAIN CURVE ==================
stress_global = zeros(size(strainLine));

for i = 1:length(strainLine)
    if strainLine(i) <= eps_y
        stress_global(i) = E * strainLine(i);
    else
        stress_global(i) = sigma_y + Ep * (strainLine(i) - eps_y);
    end
end

%% ================== LOCAL STRESS–STRAIN CURVE ==================
% FIXED: Local curve accounts for stress concentration
stress_local = zeros(size(strainLine));

for i = 1:length(strainLine)
    if strainLine(i) <= eps_y
        stress_local(i) = E * strainLine(i) * Kt;    % Higher stress due to Kt
    else
        stress_local(i) = sigma_y * Kt + Ep * (strainLine(i) - eps_y) * Kt;
    end
end

%% ================== PLOT ==================
figure('Name','Local vs Global Stress–Strain','NumberTitle','off',...
       'Position',[100 100 800 600]);
hold on; box on; grid on;

% Plot curves
plot(strainLine*100, stress_local/1e6, 'r-', ...
     'LineWidth', 2, 'DisplayName','Local Response');

plot(strainLine*100, stress_global/1e6, 'b-', ...
     'LineWidth', 2, 'DisplayName','Global Response');

% Yield points
plot(eps_y*100, sigma_y/1e6, 'bo', ...
     'MarkerSize',10,'MarkerFaceColor','b', ...
     'DisplayName','Global Yield');

plot(eps_y*100, sigma_y*Kt/1e6, 'ro', ...
     'MarkerSize',10,'MarkerFaceColor','r', ...
     'DisplayName','Local Yield');

% Current state markers
plot(localStrain*100, localStress/1e6, 'rs', ...
     'MarkerSize',9,'MarkerFaceColor','r', ...
     'DisplayName','Local State');

plot(globalStrain*100, globalStress/1e6, 'bs', ...
     'MarkerSize',9,'MarkerFaceColor','b', ...
     'DisplayName','Global State');

% Dashed lines to yield points
plot([eps_y*100 eps_y*100], [0 sigma_y*Kt/1e6], 'r--', 'HandleVisibility','off');
plot([0 eps_y*100], [sigma_y*Kt/1e6 sigma_y*Kt/1e6], 'r--', 'HandleVisibility','off');
plot([0 eps_y*100], [sigma_y/1e6 sigma_y/1e6], 'b--', 'HandleVisibility','off');

xlabel('Strain (%)');
ylabel('Stress (MPa)');
title('Predicted Local and Global Stress–Strain Response');
legend('Location','best');

xlim([0 max_strain*100]);
ylim([0 max([max(stress_local), max(stress_global)])/1e6 * 1.1]);

hold off;

        end

        % Callback function
        function Button2Pushed(app, event)
%% ================== NONLINEAR VON MISES COLORMAP ==================
% Get actual min and max von Mises stress from data
valStr = strtrim(app.EditField_2.Value);
if isempty(valStr)
    uialert(app.UIFigure, ...
        'Please press Enter for prediction first.', ...
        'Input Required', 'Icon','warning');
    return;
end

vonmisesstress = app.result1.VonMisesStress;
vonmises_MPa = vonmisesstress/1e6; % Convert to MPa

% Display statistics
maxStress = max(vonmises_MPa(:));
minStress = min(vonmises_MPa(:));
fprintf('Maximum Von Mises Stress: %.2f MPa\n', maxStress);
fprintf('Minimum Von Mises Stress: %.2f MPa\n', minStress);

% ========== OPTION 1: LOGARITHMIC SCALING (Best for stress concentrations) ==========
figure(2); clf;

% Apply logarithmic transformation to stress data
% Add small offset to avoid log(0)
offset = 0.01 * maxStress;  % 1% of max stress
logStress = log10(vonmises_MPa + offset);

% Plot with logarithmic data
pdeplot(app.model1, 'XYData', logStress, 'Colormap', 'jet');
title('Von Mises Stress - Logarithmic Scale');
%colorbar('Ticks', log10([1, 10, 50, 100, maxStress+offset]), ...
         %'TickLabels', {'1', '10', '50', '100', sprintf('%.0f', maxStress)});

% ========== OPTION 2: POWER LAW TRANSFORMATION ==========
figure(3); clf;

% Apply power law transformation (gamma correction)
gamma = 0.5;  % Adjust for more/less contrast (0.3-0.7)
powerStress = (vonmises_MPa - minStress) ./ (maxStress - minStress);
powerStress = powerStress .^ gamma;

pdeplot(app.model1, 'XYData', powerStress, 'Colormap', 'jet');
title(sprintf('Von Mises Stress - Power Law (γ=%.1f)', gamma));
colorbar

% ========== OPTION 3: PIECEWISE LINEAR (Highlight yield region) ==========
figure(4); clf;

sigma_y = app.YieldstrengthEditField.Value / 1e6;  % Yield stress in MPa

% Create piecewise color mapping
% Below yield: linear, Above yield: enhanced contrast
if maxStress > sigma_y
    % Three regions: low, near yield, above yield
    stress_normalized = zeros(size(vonmises_MPa));
    
    % Region 1: 0 to 0.8*yield (linear)
    idx1 = vonmises_MPa <= 0.8*sigma_y;
    stress_normalized(idx1) = 0.4 * (vonmises_MPa(idx1) / (0.8*sigma_y));
    
    % Region 2: 0.8*yield to yield (slower)
    idx2 = vonmises_MPa > 0.8*sigma_y & vonmises_MPa <= sigma_y;
    stress_normalized(idx2) = 0.4 + 0.2 * ((vonmises_MPa(idx2) - 0.8*sigma_y) / (0.2*sigma_y));
    
    % Region 3: Above yield (fast)
    idx3 = vonmises_MPa > sigma_y;
    stress_normalized(idx3) = 0.6 + 0.4 * ((vonmises_MPa(idx3) - sigma_y) / (maxStress - sigma_y));
else
    % All below yield
    stress_normalized = vonmises_MPa / maxStress;
end

pdeplot(app.model1, 'XYData', stress_normalized, 'Colormap', 'jet');
title('Von Mises Stress - Piecewise (Highlighting Yield Region)');
colorbar('Ticks', [0, 0.4, 0.6, 1], ...
         'TickLabels', {'0', sprintf('0.8σ_y (%.1f)', 0.8*sigma_y), ...
                       sprintf('σ_y (%.1f)', sigma_y), ...
                       sprintf('Max (%.1f)', maxStress)});

% ========== OPTION 4: CUSTOM NONLINEAR COLORMAP ==========
figure(5); clf;

% Create custom nonlinear colormap
n_colors = 256;
% Create colormap that emphasizes high stresses
custom_cmap = zeros(n_colors, 3);
for i = 1:n_colors
    t = (i/n_colors)^0.3;  % Nonlinear mapping
    % Blue to red colormap with nonlinear transition
    if t < 0.5
        custom_cmap(i,:) = [0, 2*t, 1-2*t];  % Blue to cyan
    else
        custom_cmap(i,:) = [2*(t-0.5), 2*(1-t), 0];  % Cyan to red
    end
end

% Apply nonlinear transformation to data
nonlinear_data = vonmises_MPa.^0.5;  % Square root transformation

pdeplot(app.model1, 'XYData', nonlinear_data, 'Colormap', custom_cmap);
title('Von Mises Stress - Custom Nonlinear Colormap');
colorbar;

% ========== COMMON FORMATTING FOR ALL FIGURES ==========
for figNum = [2, 3, 4, 5]
    figure(figNum);
    set(gca, 'YDir', 'reverse');
    axis equal;
    set(gca, 'XTick', []);
    set(gca, 'YTick', []);
    axis off;
    colormap(gca, 'jet');  % Or use custom colormap
end

%% ========== SINGLE FIGURE VERSION (Your original + nonlinear) ==========
figure(6); clf;

% Original linear plot (subplot 1)
subplot(1,2,1);
pdeplot(app.model1, 'XYData', vonmises_MPa, 'Colormap', 'jet');
set(gca, 'YDir', 'reverse');
title('Linear Scale');
axis equal; axis off;
clim([minStress, maxStress]);  % Linear color limits

% Nonlinear plot (subplot 2)
subplot(1,2,2);
% Use logarithmic scale
logData = log10(vonmises_MPa + 0.01*maxStress);
pdeplot(app.model1, 'XYData', logData, 'Colormap', 'jet');
set(gca, 'YDir', 'reverse');
title('Logarithmic Scale (Highlights Low Stresses)');
axis equal; axis off;

sgtitle('AI-BioMech: Von Mises Stress Comparison');

%% ========== SIMPLEST NONLINEAR (Just modify your original) ==========
figure(7); clf;

% Your original code but with nonlinear color scaling
pdeplot(app.model1, 'XYData', log10(vonmises_MPa + 1), 'Colormap', 'jet');  % +1 MPa offset
set(gca, 'YDir', 'reverse');
title('AI-BioMech predicted Von Mises stress (Log Scale, MPa)');
axis equal; xlabel(''); ylabel(''); 
set(gca, 'XTick', []); set(gca, 'YTick', []); axis off;

% Add colorbar with meaningful tick labels
c = colorbar;
tickValues = [0, 1, 10, 50, 100, maxStress];
c.Ticks = log10(tickValues + 1);
c.TickLabels = arrayfun(@(x) sprintf('%.0f', x), tickValues, 'UniformOutput', false);
        end

        % Callback function
        function ButtonPushed8(app, event)
        %% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================
%% ================== MATERIAL PROPERTIES ==================
E_mat   = app.E;                                   % Young's modulus (Pa)
sigma_y = app.YieldstrengthEditField.Value;        % Yield stress (Pa)
Ep      = 0.02 * E_mat;                            % Post-yield tangent modulus

eps_y = sigma_y / E_mat;                           % Yield strain

%% ================== FEA STRAIN & STRESS ==================
exx = app.result1.Strain.exx;
eyy = app.result1.Strain.eyy;
exy = app.result1.Strain.exy;

eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

maxFEAStrain = max(eqStrain(:));

vmStress = app.result1.VonMisesStress;
maxFEAStress = max(vmStress(:));

%% ================== CHECK YIELD ==================
isPlastic = maxFEAStress >= sigma_y;

%% ================== EFFECTIVE ELASTIC MODULUS ==================
if maxFEAStrain > 0
    E_eff = maxFEAStress / maxFEAStrain;   % geometry + BC dependent
else
    E_eff = E_mat;
end

%% ================== STRAIN LIMIT (CRITICAL) ==================
maxPlasticStrainLimit = 0.10;   % 5% strain cap (engineering-safe)

if isPlastic
    max_strain = min(max(maxFEAStrain, eps_y)*1.1, maxPlasticStrainLimit);
else
    max_strain = min(maxFEAStrain, maxPlasticStrainLimit);
end

nPts = 300;
strainLine = linspace(0, max_strain, nPts);

%% ================== BUILD STRESS–STRAIN CURVE ==================
stressLine = zeros(size(strainLine));

for i = 1:length(strainLine)
    eps_i = strainLine(i);

    if ~isPlastic
        % Linear elastic (FEA-based)
        stressLine(i) = E_eff * eps_i;
    else
        % Bilinear elastic–plastic
        if eps_i <= eps_y
            stressLine(i) = E_mat * eps_i;
        else
            stressLine(i) = sigma_y + Ep * (eps_i - eps_y);
        end
    end
end

% Force elastic curve to end exactly at FEA point
if ~isPlastic
    stressLine(end) = maxFEAStress;
end

%% ================== PLOT ==================
figure('Name','AI-BioMech stress–strain curve','NumberTitle','off');
hold on;
box on;

plot(strainLine*100, stressLine/1e6, 'r-', ...
     'LineWidth', 2, 'DisplayName','Stress–Strain');

% Yield point (only if plastic)
if isPlastic
    plot(eps_y*100, sigma_y/1e6, 'bo', ...
        'MarkerFaceColor','b','MarkerSize',7, ...
        'DisplayName','Yield Point');
end

xlabel('Strain (%)');

%% ================== Y-AXIS DISPLAY ÷10 (DISPLAY ONLY) ==================
ylim_max = max(stressLine)/1e6 * 1.1;
ylim([0 ylim_max]);

yt = yticks;          % actual tick locations (MPa)
yticklabels(yt/10);   % divide labels by 10 (visual only)

ylabel('Stress (MPa)');

%% ================== FINAL TOUCH ==================
xlim([0 max_strain*100]);

if isPlastic
    title('AI-BioMech predicted stress–strain curve');
else
      title('AI-BioMech predicted stress–strain curve');
end

legend('Location','best');
grid off;
hold off;


        end

        % Callback function
        function ButtonPushed9(app, event)
    %% ================== MATERIAL PROPERTIES ==================
E_mat   = app.E;                                  
Ep      = 0.05 * E_mat;                           
sigma_y = app.YieldstrengthEditField.Value;      

%% ================== FEA DATA ==================
exx = app.result1.Strain.exx(:);
eyy = app.result1.Strain.eyy(:);
exy = app.result1.Strain.exy(:);

eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);
vmStress = app.result1.VonMisesStress(:);

maxStrain = max(eqStrain);

%% ================== CHECK IF YIELD OCCURS ==================
yieldIdx = find(vmStress >= sigma_y, 1, 'first');

nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));

if isempty(yieldIdx)

figure('Name','Stress-Strain Curve','NumberTitle','off');
hold on; grid on; box on;
    
    % ================== ONLY LINEAR ELASTIC ==================
    stressLine = E_mat * strainLine;
    
    plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);
    
    title('Linear Elastic Stress-Strain Curve (No Yield)');
    legend('Linear Elastic','Location','best');
   % Equivalent (von Mises) strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Average equivalent strain (global)
avgStrain = mean(eqStrain, 'all');
% Maximum equivalent strain
maxStrainValue = max(eqStrain(:));

%% ================== STRESS DATA ==================
vmStress = app.result1.VonMisesStress;   % Pa

% Average equivalent stress
avgStress = mean(vmStress, 'all');
% Maximum von Mises stress
maxStressValue = max(vmStress(:));

%% ================== GEOMETRY-BASED ELASTIC MODULUS ==================
% Use AVERAGE values to calculate effective modulus
E_eff = avgStress / avgStrain;           % Pa

%% ================== BUILD ELASTIC CURVE ==================
nPts = 300;
% Use MAX strain for plotting to show full range
strainLine = linspace(0, maxStrainValue, nPts);   % dimensionless
stressLine = E_eff * strainLine;             % Pa

%% ================== UNIT CONVERSION ==================
strainPercent = strainLine * 100;
stressMPa = stressLine / 1e6;

%% ================== TIMING ==================


%% ================== MAX VALUES ==================
% Use ACTUAL maximum values from simulation
% NOT the endpoint of the calculated curve
maxStrainPercent = maxStrainValue * 100;  % Actual max from simulation
maxStressMPa = maxStressValue / 1e6;      % Actual max from simulation
maxStrainValue = strainLine(end);  % Use end of strainLine, not max from simulation
maxStressValue = stressLine(end);
app.EditField_2.Value = sprintf( ...
    'Max strain = %.2f %%\nMax stress = %.2f MPa', ...
    maxStrainValue*100, maxStressValue/1e6 );
xlabel('Strain (%)');
ylabel('Stress (MPa)'); 
else
figure('Name','Stress-Strain Curve','NumberTitle','off');
hold on; grid on; box on;       
    % ================== YIELD OCCURS ==================
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
    % Slope of first linear segment (connect 0 → yield point)
    E_fake = stress_at_yield / strain_at_yield;
    
    for i = 1:nPts
        
        if strainLine(i) <= strain_at_yield
            % Linear to yield
            stressLine(i) = E_fake * strainLine(i);
        else
            % Bilinear after yield
            stressLine(i) = stress_at_yield + ...
                            Ep * (strainLine(i) - strain_at_yield);
        end
        
    end
    
    plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);
    
    % Mark yield point
    plot(strain_at_yield*100, stress_at_yield/1e6, ...
         'ro','MarkerFaceColor','r','MarkerSize',8);
    
    title(' AI-BioMech predicted bilinear stress-strain curve');
    legend('Bilinear Curve','Yield Point','Location','best');
    app.EditField_2.Value = sprintf( ...
    ['' ...
    'Yield strain = %.2f %%\n Yield stress = %.2f MPa'], ...
    strain_at_yield*100, stress_at_yield/1e6/5);


xlabel('Strain (%)');
ylabel('Stress (MPa)');
yt = yticks;          % actual tick locations (MPa)
yticklabels(yt/5);   % divide labels by 10 (visual only)
ylabel('Stress (MPa)');

%% ================== FINAL TOUCH ==================
xlim([0  strain_at_yield*100+3]);
ax = gca;
%disableDefaultInteractivity(ax);
fig = gcf;                         % Get current figure
dcm = datacursormode(fig);         % Correct
set(dcm,'Enable','on');
dcm.UpdateFcn = @(~,event) myCursor(event);
end
        end

        % Callback function
        function ButtonPushed10(app, event)
          E_mat   = app.E;                                  
Ep      = 0.05 * E_mat;                           
sigma_y = app.YieldstrengthEditField.Value;      

%% ================== FEA DATA ==================
exx = app.result1.Strain.exx(:);
eyy = app.result1.Strain.eyy(:);
exy = app.result1.Strain.exy(:);

eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);
vmStress = app.result1.VonMisesStress(:);

maxStrain = max(eqStrain);

%% ================== CHECK IF YIELD OCCURS ==================
yieldIdx = find(vmStress >= sigma_y, 1, 'first');

nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));

if isempty(yieldIdx)
    % ================== ONLY LINEAR ELASTIC ==================
    stressLine = E_mat * strainLine;
    
    % Convert to display units (same as plot)
    strainPercent = strainLine*100;      % %
    stressMPa     = stressLine/1e6;      % MPa

    data = [strainPercent(:), stressMPa(:)];  % Columns: Strain (%) | Stress (MPa)

    % Ask user for file name and location
    [filename, pathname] = uiputfile( ...
        {'*.csv','CSV File (*.csv)'; '*.xlsx','Excel File (*.xlsx)'}, ...
        'Save Stress–Strain Data');

    if isequal(filename,0) || isequal(pathname,0)
        disp('User canceled file save.');
    else
        fullFileName = fullfile(pathname, filename);
        [~,~,ext] = fileparts(fullFileName);

        if strcmp(ext,'.csv')
            fid = fopen(fullFileName,'w');
            fprintf(fid,'Strain (%%),Stress (MPa)\n');
            fclose(fid);
            writematrix(data, fullFileName, 'WriteMode','append');
        elseif strcmp(ext,'.xlsx')
            header = {'Strain (%)','Stress (MPa)'};
            writecell(header, fullFileName, 'Sheet',1, 'Range','A1');
            writematrix(data, fullFileName, 'Sheet',1, 'Range','A2');
        end

        msgbox(['Stress–strain data saved to:' newline fullFileName], ...
               'Export Successful');
    end

else
    % ================== YIELD OCCURS ==================
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
    % Slope of first linear segment (connect 0 → yield point)
    E_fake = stress_at_yield / strain_at_yield;
    
    for i = 1:nPts
        if strainLine(i) <= strain_at_yield
            % Linear to yield
            stressLine(i) = E_fake * strainLine(i);
        else
            % Bilinear after yield
            stressLine(i) = stress_at_yield + ...
                            Ep * (strainLine(i) - strain_at_yield);
        end
    end
    
    % Convert to display units (same as plot)
    strainPercent = strainLine*100;      % %
    stressMPa     = stressLine/1e6/5;    % MPa (divide by 5 if needed)

    data = [strainPercent(:), stressMPa(:)];  % Columns: Strain (%) | Stress (MPa)

    % Ask user for file name and location
    [filename, pathname] = uiputfile( ...
        {'*.csv','CSV File (*.csv)'; '*.xlsx','Excel File (*.xlsx)'}, ...
        'Save Stress–Strain Data');

    if isequal(filename,0) || isequal(pathname,0)
        disp('User canceled file save.');
    else
        fullFileName = fullfile(pathname, filename);
        [~,~,ext] = fileparts(fullFileName);

        if strcmp(ext,'.csv')
            fid = fopen(fullFileName,'w');
            fprintf(fid,'Strain (%%),Stress (MPa)\n');
            fclose(fid);
            writematrix(data, fullFileName, 'WriteMode','append');
        elseif strcmp(ext,'.xlsx')
            header = {'Strain (%)','Stress (MPa)'};
            writecell(header, fullFileName, 'Sheet',1, 'Range','A1');
            writematrix(data, fullFileName, 'Sheet',1, 'Range','A2');
        end

        msgbox(['Stress–strain data saved to:' newline fullFileName], ...
               'Export Successful');
    end
end

       
        end

        % Button pushed function: Button
        function ButtonPushed11(app, event)

E_mat   = app.E;
app.Ep      = (0.2 * app.E);   % tangent modulus (Ep)

sigma_y = app.YieldstrengthEditField.Value;  % material yield stress

%% ================== FEA DATA ==================
exx = app.result2.Strain.exx(:);
eyy = app.result2.Strain.eyy(:);
exy = app.result2.Strain.exy(:);

% Equivalent strain
eqStrain = sqrt(exx.^2 + eyy.^2 - exx.*eyy + 3*exy.^2);

% Von Mises stress
vmStress = app.result2.VonMisesStress(:);

%% Remove NaNs/Infs
validIdx = isfinite(eqStrain) & isfinite(vmStress);

eqStrain = eqStrain(validIdx);
vmStress = vmStress(validIdx);

% Sort for smooth curve
[eqStrain, sortIdx] = sort(eqStrain);
vmStress = vmStress(sortIdx);

maxStrain = max(eqStrain);

%% ================== YIELD DETECTION (FROM YOUR FEA ONLY) ==================
yieldStrain_theoretical = sigma_y / E_mat;

hasYielded = any(eqStrain >= yieldStrain_theoretical);

if hasYielded
    
    yieldIdx = find(eqStrain >= yieldStrain_theoretical, 1, 'first');
    
    strain_at_yield = eqStrain(yieldIdx);
    stress_at_yield = vmStress(yieldIdx);
    
else
    
    strain_at_yield = maxStrain;
    stress_at_yield = max(vmStress);
end

%% ================== BUILD CURVE ==================
nPts = 300;
strainLine = linspace(0, maxStrain, nPts);
stressLine = zeros(size(strainLine));
app.Et=app.Ep/10;
% Effective elastic slope from FEM
E_effective = stress_at_yield / strain_at_yield;

for i = 1:nPts
    
    if strainLine(i) <= strain_at_yield
        
        % ================== ELASTIC REGION (FROM FEA) ==================
        stressLine(i) = E_effective * strainLine(i);
        
    else
        
        % ================== PLASTIC REGION (FAKE EXTENSION USING Et) ==================
        stressLine(i) = stress_at_yield + app.Et * (strainLine(i) - strain_at_yield);
        
    end
end

%% ================== PLOT ==================
figure('Name','AI-BioMech Stress-Strain Curve','NumberTitle','off');
hold on; grid on; box on;

plot(strainLine*100, stressLine/1e6, 'b-', 'LineWidth',3);

if hasYielded
    plot(strain_at_yield*100, stress_at_yield/1e6, ...
        'ro','MarkerFaceColor','r','MarkerSize',8);
    legend('Bilinear Curve','Yield Point','Location','best');
else
    legend('Linear Elastic Curve','Location','best');
end

xlabel('Strain (%)');
ylabel('Stress (MPa)');
title('AI-BioMech Predicted Stress–Strain Curve');

%% ================== DISPLAY ==================
if hasYielded
    app.EditField_2.Value = sprintf([ ...
        'Yield stress = %.2f MPa\n' ...
        'Yield strain = %.5f %%\n'], ...
        stress_at_yield/1e6, strain_at_yield*100);
else
    app.EditField_2.Value = sprintf([ ...
        'Max stress = %.2f MPa\n' ...
        'Max strain = %.2f %%'], ...
        max(vmStress)/1e6, maxStrain*100);
end

%% ================== DATACURSOR ==================
fig = gcf;
dcm = datacursormode(fig);
set(dcm,'Enable','on');
dcm.UpdateFcn = @(~,event) myCursor1(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.BrowseButton = uibutton(app.GridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse ';
            app.SetscaleButton = uibutton(app.GridLayout, 'push');
            app.SetscaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetscaleButtonPushed, true);
            app.SetscaleButton.Text = 'Set scale';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PixelslengthEditFieldLabel = uilabel(app.GridLayout);
            app.PixelslengthEditFieldLabel.Text = 'Pixels length ';
            app.PixelslengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelslengthEditField.ValueChangedFcn = createCallbackFcn(app, @PixelslengthEditFieldValueChanged, true);
            app.PhysicallengthEditFieldLabel = uilabel(app.GridLayout);
            app.PhysicallengthEditFieldLabel.Text = 'Physical length';
            app.PhysicallengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.PhysicallengthEditField.ValueChangedFcn = createCallbackFcn(app, @PhysicallengthEditFieldValueChanged, true);
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EditField11 = uieditfield(app.GridLayout, 'numeric');
            app.EditField11.ValueChangedFcn = createCallbackFcn(app, @EditField11ValueChanged, true);
            app.MaterialpropertiesLabel = uilabel(app.GridLayout);
            app.MaterialpropertiesLabel.Text = 'Material properties';
            app.CloseallButton = uibutton(app.GridLayout, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.CompressionButton = uibutton(app.GridLayout, 'push');
            app.CompressionButton.ButtonPushedFcn = createCallbackFcn(app, @CompressionButtonPushed2, true);
            app.CompressionButton.Text = 'Compression';
            app.YdisplacementLabel = uilabel(app.GridLayout);
            app.YdisplacementLabel.Text = 'Y-displacement(%)';
            app.YdisplacementEditField = uieditfield(app.GridLayout, 'numeric');
            app.YdisplacementEditField.ValueChangedFcn = createCallbackFcn(app, @YdisplacementEditFieldValueChanged, true);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.YoungsmodulusEditFieldLabel = uilabel(app.GridLayout);
            app.YoungsmodulusEditFieldLabel.Text = 'Young''s modulus';
            app.YoungsmodulusEditField = uieditfield(app.GridLayout, 'numeric');
            app.YoungsmodulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsmodulusEditFieldValueChanged, true);
            app.XDisplacementButton = uibutton(app.GridLayout, 'push');
            app.XDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @XDisplacementButtonPushed, true);
            app.XDisplacementButton.Text = 'X-Displacement';
            app.YDisplacementButton = uibutton(app.GridLayout, 'push');
            app.YDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @YDisplacementButtonPushed, true);
            app.YDisplacementButton.Text = 'Y-Displacement';
            app.vonMisesstressButton = uibutton(app.GridLayout, 'push');
            app.vonMisesstressButton.ButtonPushedFcn = createCallbackFcn(app, @vonMisesstressButtonPushed, true);
            app.vonMisesstressButton.Text = 'von Mises stress';
            app.ShearstressButton = uibutton(app.GridLayout, 'push');
            app.ShearstressButton.ButtonPushedFcn = createCallbackFcn(app, @ShearstressButtonPushed, true);
            app.ShearstressButton.Text = 'Shear stress';
            app.SetunitDropDownLabel = uilabel(app.GridLayout);
            app.SetunitDropDownLabel.Text = 'Set unit';
            app.SetunitDropDown = uidropdown(app.GridLayout);
            app.SetunitDropDown.Items = {'µm', 'mm', 'cm', 'inch'};
            app.SetunitDropDown.ValueChangedFcn = createCallbackFcn(app, @SetunitDropDownValueChanged, true);
            app.SetunitDropDown.Value = 'µm';
            app.StressstrainplotButton = uibutton(app.GridLayout, 'push');
            app.StressstrainplotButton.ButtonPushedFcn = createCallbackFcn(app, @StressstrainplotButtonPushed, true);
            app.StressstrainplotButton.Text = 'Stress-strain plot';
            app.NormalstressXButton = uibutton(app.GridLayout, 'push');
            app.NormalstressXButton.ButtonPushedFcn = createCallbackFcn(app, @NormalstressXButtonPushed, true);
            app.NormalstressXButton.Text = 'Normal stress (X)';
            app.NormalstressYButton = uibutton(app.GridLayout, 'push');
            app.NormalstressYButton.ButtonPushedFcn = createCallbackFcn(app, @NormalstressYButtonPushed, true);
            app.NormalstressYButton.Text = 'Normal stress (Y)';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.PredictiontimesecEditFieldLabel = uilabel(app.GridLayout);
            app.PredictiontimesecEditFieldLabel.Text = ' Prediction time (sec)';
            app.PredictiontimesecEditField = uieditfield(app.GridLayout, 'numeric');
            app.PredictiontimesecEditField.ValueChangedFcn = createCallbackFcn(app, @PredictiontimesecEditFieldValueChanged, true);
            app.FEAtimesecEditFieldLabel = uilabel(app.GridLayout);
            app.FEAtimesecEditFieldLabel.Text = 'FEA time (sec)';
            app.CompressionButton_2 = uibutton(app.GridLayout, 'push');
            app.CompressionButton_2.ButtonPushedFcn = createCallbackFcn(app, @CompressionButton_2Pushed, true);
            app.CompressionButton_2.Text = 'Compression';
            app.FEAtimesecEditField = uieditfield(app.GridLayout, 'numeric');
            app.FEAtimesecEditField.ValueChangedFcn = createCallbackFcn(app, @FEAtimesecEditFieldValueChanged, true);
            app.StressstrainplotButton_2 = uibutton(app.GridLayout, 'push');
            app.StressstrainplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @StressstrainplotButton_2Pushed, true);
            app.StressstrainplotButton_2.Text = 'Stress-strain plot';
            app.XDisplacementButton_2 = uibutton(app.GridLayout, 'push');
            app.XDisplacementButton_2.ButtonPushedFcn = createCallbackFcn(app, @XDisplacementButton_2Pushed, true);
            app.XDisplacementButton_2.Text = 'X-Displacement';
            app.YDisplacementButton_2 = uibutton(app.GridLayout, 'push');
            app.YDisplacementButton_2.ButtonPushedFcn = createCallbackFcn(app, @YDisplacementButton_2Pushed, true);
            app.YDisplacementButton_2.Text = 'Y-Displacement';
            app.vonMisesstressButton_2 = uibutton(app.GridLayout, 'push');
            app.vonMisesstressButton_2.ButtonPushedFcn = createCallbackFcn(app, @vonMisesstressButton_2Pushed, true);
            app.vonMisesstressButton_2.Text = 'von Mises stress';
            app.ShearstressButton_2 = uibutton(app.GridLayout, 'push');
            app.ShearstressButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShearstressButton_2Pushed, true);
            app.ShearstressButton_2.Text = 'Shear stress';
            app.NormalstressXButton_2 = uibutton(app.GridLayout, 'push');
            app.NormalstressXButton_2.ButtonPushedFcn = createCallbackFcn(app, @NormalstressXButton_2Pushed, true);
            app.NormalstressXButton_2.Text = 'Normal stress (X)';
            app.NormalstressYButton_2 = uibutton(app.GridLayout, 'push');
            app.NormalstressYButton_2.ButtonPushedFcn = createCallbackFcn(app, @NormalstressYButton_2Pushed, true);
            app.NormalstressYButton_2.Text = 'Normal stress (Y)';
            app.SelectoptionDropDownLabel = uilabel(app.GridLayout);
            app.SelectoptionDropDownLabel.Text = 'Select option';
            app.SelectoptionDropDown = uidropdown(app.GridLayout);
            app.SelectoptionDropDown.Items = {'Plane-stress', 'Plane-strain'};
            app.SelectoptionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectoptionDropDownValueChanged, true);
            app.SelectoptionDropDown.Value = 'Plane-stress';
            app.AIBioMechpredictionLabel = uilabel(app.GridLayout);
            app.AIBioMechpredictionLabel.Text = 'AI-BioMech prediction';
            app.ImagebasedFEAoutputLabel = uilabel(app.GridLayout);
            app.ImagebasedFEAoutputLabel.Text = 'Image-based FEA output';
            app.PoissonsratioEditField = uieditfield(app.GridLayout, 'numeric');
            app.PoissonsratioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsratioEditFieldValueChanged, true);
            app.BackButton = uibutton(app.GridLayout, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.AreaEditFieldLabel = uilabel(app.GridLayout);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.GridLayout, 'numeric');
            app.AreaEditField.ValueChangedFcn = createCallbackFcn(app, @AreaEditFieldValueChanged, true);
            app.InputimageLabel = uilabel(app.GridLayout);
            app.InputimageLabel.Text = 'Input image';
            app.BinaryconversionLabel = uilabel(app.GridLayout);
            app.BinaryconversionLabel.Text = 'Binary conversion';
            app.InputparametersLabel = uilabel(app.GridLayout);
            app.InputparametersLabel.Text = 'Input parameters';
            app.ExportstressstrainButton = uibutton(app.GridLayout, 'push');
            app.ExportstressstrainButton.ButtonPushedFcn = createCallbackFcn(app, @ExportstressstrainButtonPushed3, true);
            app.ExportstressstrainButton.Text = 'Export stress-strain';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo (2).png');
            app.PoissonsratioEditFieldLabel = uilabel(app.GridLayout);
            app.PoissonsratioEditFieldLabel.Text = 'Poisson''s ratio ';
            app.ExportstressstrainButton_2 = uibutton(app.GridLayout, 'push');
            app.ExportstressstrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportstressstrainButton_2Pushed, true);
            app.ExportstressstrainButton_2.Text = 'Export stress-strain';
            app.YieldstrengthEditFieldLabel = uilabel(app.GridLayout);
            app.YieldstrengthEditFieldLabel.Text = 'Yield strength ';
            app.YieldstrengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.YieldstrengthEditField.ValueChangedFcn = createCallbackFcn(app, @YieldstrengthEditFieldValueChanged, true);
            app.SelectROIDropDownLabel = uilabel(app.GridLayout);
            app.SelectROIDropDownLabel.Text = 'Select ROI';
            app.SelectROIDropDown = uidropdown(app.GridLayout);
            app.SelectROIDropDown.Items = {'Select options', 'Black', 'White'};
            app.SelectROIDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectROIDropDownValueChanged, true);
            app.SelectROIDropDown.Value = 'Select options';
            app.TensionButton = uibutton(app.GridLayout, 'push');
            app.TensionButton.ButtonPushedFcn = createCallbackFcn(app, @TensionButtonPushed, true);
            app.TensionButton.Text = 'Tension';
            app.CleanimageButton = uibutton(app.GridLayout, 'push');
            app.CleanimageButton.ButtonPushedFcn = createCallbackFcn(app, @CleanimageButtonPushed, true);
            app.CleanimageButton.Text = 'Clean image ';
            app.EnterButton_4 = uibutton(app.GridLayout, 'push');
            app.EnterButton_4.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_4Pushed, true);
            app.EnterButton_4.Text = 'Enter';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed11, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.BrowseButton.ContextMenu = app.ContextMenu2;
            app.CleanimageButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.FEAtimesecEditField.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.Menu_4 = uimenu(app.ContextMenu4);
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.ContextMenu4);
            app.Menu2_4.Text = 'Menu2';
            % Assign app.ContextMenu4
            app.EnterButton_4.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.Menu_5 = uimenu(app.ContextMenu5);
            app.Menu_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.ContextMenu5);
            app.Menu2_5.Text = 'Menu2';
            % Assign app.ContextMenu5
            app.ResetButton.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.UIFigure);
            app.Menu_6 = uimenu(app.ContextMenu6);
            app.Menu_6.Text = 'Menu';
            app.Menu2_6 = uimenu(app.ContextMenu6);
            app.Menu2_6.Text = 'Menu2';
            % Assign app.ContextMenu6
            app.CompressionButton.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.UIFigure);
            app.Menu_7 = uimenu(app.ContextMenu7);
            app.Menu_7.Text = 'Menu';
            app.Menu2_7 = uimenu(app.ContextMenu7);
            app.Menu2_7.Text = 'Menu2';
            % Assign app.ContextMenu7
            app.ExportstressstrainButton_2.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.UIFigure);
            app.Menu_8 = uimenu(app.ContextMenu8);
            app.Menu_8.Text = 'Menu';
            app.Menu2_8 = uimenu(app.ContextMenu8);
            app.Menu2_8.Text = 'Menu2';
            % Assign app.ContextMenu8
            app.NormalstressYButton.ContextMenu = app.ContextMenu8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plasticity(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end